classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadCondifurationsMenu          matlab.ui.container.Menu
        SaveConfigurationsMenu          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        DeactivatePeripheralsMenu       matlab.ui.container.Menu
        ExtraFiguresMenu                matlab.ui.container.Menu
        PMTSaturationInspector          matlab.ui.container.Menu
        ScanAssistantMenu               matlab.ui.container.Menu
        FindMyinstrumentPortMenu        matlab.ui.container.Menu
        InstrumentsMenu                 matlab.ui.container.Menu
        StagesMenu                      matlab.ui.container.Menu
        ScanningAssistantMenu           matlab.ui.container.Menu
        AFGMenu                         matlab.ui.container.Menu
        OscilloscopeMenu                matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AcoustoOpticSystemLabel         matlab.ui.control.Label
        ScanPlotTab                     matlab.ui.container.TabGroup
        MainAxisTab                     matlab.ui.container.Tab
        axMainAxis                      matlab.ui.control.UIAxes
        MainPlaneTab                    matlab.ui.container.Tab
        axMainPlane                     matlab.ui.control.UIAxes
        NavigatorTab                    matlab.ui.container.Tab
        axNav                           matlab.ui.control.UIAxes
        MainAxisAveragedTab             matlab.ui.container.Tab
        axMainAxisAvg                   matlab.ui.control.UIAxes
        MainPlaneAveragedTab            matlab.ui.container.Tab
        axMainPlaneAvg                  matlab.ui.control.UIAxes
        NavigatorAveragedTab            matlab.ui.container.Tab
        axNavAvg                        matlab.ui.control.UIAxes
        DeepViewPlotTab                 matlab.ui.container.Tab
        axDeepView                      matlab.ui.control.UIAxes
        ConsistencyPlotTab              matlab.ui.container.Tab
        axConsistency                   matlab.ui.control.UIAxes
        CurrentFrameTab                 matlab.ui.container.Tab
        axCurFrame                      matlab.ui.control.UIAxes
        FramesNavigatorTab              matlab.ui.container.Tab
        axFrameNav                      matlab.ui.control.UIAxes
        SimulationSavePanel             matlab.ui.container.Panel
        MeasurementNameEditFieldLabel   matlab.ui.control.Label
        MeasurementNameEditField        matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        saveResultsandVarsCheckBox      matlab.ui.control.CheckBox
        saveFigsCheckBox                matlab.ui.control.CheckBox
        saveRawDataCheckBox             matlab.ui.control.CheckBox
        saveNetSignalCheckBox           matlab.ui.control.CheckBox
        saveDeMultiplexedCheckBox       matlab.ui.control.CheckBox
        saveReshapedCheckBox            matlab.ui.control.CheckBox
        saveFFTCheckBox                 matlab.ui.control.CheckBox
        SaveSpecificDataLabel           matlab.ui.control.Label
        saveUSCmplxCheckBox             matlab.ui.control.CheckBox
        dirBrowseButton                 matlab.ui.control.Button
        MeasMethod                      matlab.ui.container.TabGroup
        SingleMeasurementTab            matlab.ui.container.Tab
        AORunButton                     matlab.ui.control.Button
        AOLiveRunButton                 matlab.ui.control.Button
        StagesPanel_3                   matlab.ui.container.Panel
        mmLabel_17                      matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        XpositionEditField_2Label       matlab.ui.control.Label
        AOXpositionEditField            matlab.ui.control.NumericEditField
        YpositionEditField_2Label       matlab.ui.control.Label
        AOYpositionEditField            matlab.ui.control.NumericEditField
        AOMeasHereCheckBox              matlab.ui.control.CheckBox
        LiveAcoustoOpticsPanel          matlab.ui.container.Panel
        LimitLiveAOCheckBox             matlab.ui.control.CheckBox
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        ImportsavedResultsPanel         matlab.ui.container.Panel
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        analysisDataPathButton          matlab.ui.control.Button
        AnalyzeOrigButton               matlab.ui.control.Button
        VarsEditFieldLabel              matlab.ui.control.Label
        VarsEditField                   matlab.ui.control.EditField
        analysisVarsPathButton          matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        AnalyizeNewButton               matlab.ui.control.Button
        UseVirtualDataCheckBox          matlab.ui.control.CheckBox
        ImportVarsButton                matlab.ui.control.Button
        ExportVarsButton                matlab.ui.control.Button
        Scan2DTab                       matlab.ui.container.Tab
        S2DGridPanel                    matlab.ui.container.Panel
        S2DFirstScanAxisLabel           matlab.ui.control.Label
        AxisDropDownLabel_2             matlab.ui.control.Label
        S2DFirstAxisDropDown            matlab.ui.control.DropDown
        mmLabel_20                      matlab.ui.control.Label
        StartEditFieldLabel_2           matlab.ui.control.Label
        S2DFirstStartEditField          matlab.ui.control.NumericEditField
        mmLabel_21                      matlab.ui.control.Label
        StrideEditFieldLabel_2          matlab.ui.control.Label
        S2DFirstStrideEditField         matlab.ui.control.NumericEditField
        mmLabel_22                      matlab.ui.control.Label
        EndEditFieldLabel_2             matlab.ui.control.Label
        S2DFirstEndEditField            matlab.ui.control.NumericEditField
        SecondScanAxisLabel             matlab.ui.control.Label
        AxisDropDown_2Label_2           matlab.ui.control.Label
        S2DSecondAxisDropDown           matlab.ui.control.DropDown
        mmLabel_23                      matlab.ui.control.Label
        PosLabel                        matlab.ui.control.Label
        S2DSecondPosEditField           matlab.ui.control.NumericEditField
        S2DRunButton                    matlab.ui.control.Button
        S2DRepeatsPanel                 matlab.ui.container.Panel
        RepeatsEditFieldLabel_2         matlab.ui.control.Label
        S2DRepeatsEditField             matlab.ui.control.NumericEditField
        Scan3DTab                       matlab.ui.container.Tab
        s3DGridPanel                    matlab.ui.container.Panel
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        StartEditFieldLabel             matlab.ui.control.Label
        S3DFirstStartEditField          matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        S3DFirstEndEditField            matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        S3DSecondStartEditField         matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        S3DSecondEndEditField           matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        S3DFirstStrideEditField         matlab.ui.control.NumericEditField
        StrideEditField_2Label          matlab.ui.control.Label
        S3DSecondStrideEditField        matlab.ui.control.NumericEditField
        AxisDropDownLabel               matlab.ui.control.Label
        S3DFirstAxisDropDown            matlab.ui.control.DropDown
        S3DImportLimitsfromfileButton   matlab.ui.control.Button
        S3DFirstScanAxisLabel           matlab.ui.control.Label
        S3DSecondScanAxisLabel          matlab.ui.control.Label
        AxisDropDown_2Label             matlab.ui.control.Label
        S3DSecondAxisDropDown           matlab.ui.control.DropDown
        S3DRunButton                    matlab.ui.control.Button
        S3DRepeatsPanel                 matlab.ui.container.Panel
        RepeatsEditFieldLabel           matlab.ui.control.Label
        S3DRepeatsEditField             matlab.ui.control.NumericEditField
        S3DImportResultsButton          matlab.ui.control.Button
        S3DExportScanVarsButton         matlab.ui.control.Button
        S3DImportScanVarsButton         matlab.ui.control.Button
        DeepViewTab                     matlab.ui.container.Tab
        DVGridPanel                     matlab.ui.container.Panel
        DVFirstScanAxisLabel            matlab.ui.control.Label
        AxisDropDownLabel_3             matlab.ui.control.Label
        DVFirstAxisDropDown             matlab.ui.control.DropDown
        mmLabel_25                      matlab.ui.control.Label
        StartEditFieldLabel_3           matlab.ui.control.Label
        DVFirstStartEditField           matlab.ui.control.NumericEditField
        mmLabel_26                      matlab.ui.control.Label
        StrideEditFieldLabel_3          matlab.ui.control.Label
        DVFirstStrideEditField          matlab.ui.control.NumericEditField
        mmLabel_27                      matlab.ui.control.Label
        EndEditFieldLabel_3             matlab.ui.control.Label
        DVFirstEndEditField             matlab.ui.control.NumericEditField
        DVSecondScanAxisLabel           matlab.ui.control.Label
        AxisDropDown_2Label_3           matlab.ui.control.Label
        DVSecondAxisDropDown            matlab.ui.control.DropDown
        mmLabel_28                      matlab.ui.control.Label
        PosLabel_2                      matlab.ui.control.Label
        DVSecondPosEditField            matlab.ui.control.NumericEditField
        DVFiguresPanel                  matlab.ui.container.Panel
        mmLabel_29                      matlab.ui.control.Label
        DepthCoorMainAxisLabel_4        matlab.ui.control.Label
        DVCurDepthCoorSpinner           matlab.ui.control.Spinner
        TimePanel                       matlab.ui.container.Panel
        sLabel_7                        matlab.ui.control.Label
        StartEditFieldLabel_4           matlab.ui.control.Label
        DVTimeStartEditField            matlab.ui.control.NumericEditField
        sLabel_8                        matlab.ui.control.Label
        StrideEditFieldLabel_4          matlab.ui.control.Label
        DVTimeStrideEditField           matlab.ui.control.NumericEditField
        sLabel_9                        matlab.ui.control.Label
        EndEditFieldLabel_4             matlab.ui.control.Label
        DVTimeEndEditField              matlab.ui.control.NumericEditField
        DVRunButton                     matlab.ui.control.Button
        ConsistencyTab                  matlab.ui.container.Tab
        RunButtonConsistency            matlab.ui.control.Button
        StagesPanel_2                   matlab.ui.container.Panel
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        TimeFramePropertiesPanel        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        MaxTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MaxTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        MinTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MinTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        TimeStrideEditFieldLabel        matlab.ui.control.Label
        TimeStrideEditField             matlab.ui.control.NumericEditField
        SetsperTimeFrameEditFieldLabel  matlab.ui.control.Label
        SetsperTimeFrameEditField       matlab.ui.control.NumericEditField
        ImportResultsButton_2           matlab.ui.control.Button
        ExportScanVarsButton_2          matlab.ui.control.Button
        ImportScanVarsButton_2          matlab.ui.control.Button
        FiguresPanel_2                  matlab.ui.container.Panel
        FrameToPlotSpinnerLabel         matlab.ui.control.Label
        FrameToPlotSpinner              matlab.ui.control.Spinner
        SettoplotSpinnerLabel           matlab.ui.control.Label
        SettoplotSpinner                matlab.ui.control.Spinner
        ZCoorSpinnerLabel               matlab.ui.control.Label
        ZCoorConsSpinner                matlab.ui.control.Spinner
        sLabel_6                        matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        GlobalParameters                matlab.ui.container.TabGroup
        SignalExcitationTab             matlab.ui.container.Tab
        UseQuantCheckBox                matlab.ui.control.CheckBox
        SamplingFrequencyextClkPanel    matlab.ui.container.Panel
        MHzLabel_2                      matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_4Label       matlab.ui.control.Label
        AmplitudeEditField_4            matlab.ui.control.EditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.EditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        UltrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        MHzLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        SineFrequencyEditFieldLabel     matlab.ui.control.Label
        SineFrequencyEditField          matlab.ui.control.NumericEditField
        TrainFrequencyEditFieldLabel    matlab.ui.control.Label
        TrainFrequencyEditField         matlab.ui.control.NumericEditField
        SinePeriodsEditFieldLabel       matlab.ui.control.Label
        SinePeriodsEditField            matlab.ui.control.NumericEditField
        OptimizetrainfrequencyCheckBox  matlab.ui.control.CheckBox
        GeometryPanel                   matlab.ui.container.Panel
        PhantomdepthEditFieldLabel      matlab.ui.control.Label
        PhantomdepthEditField           matlab.ui.control.NumericEditField
        TransducerheightEditFieldLabel  matlab.ui.control.Label
        TransducerheightEditField       matlab.ui.control.NumericEditField
        TransducerdistanceLabel         matlab.ui.control.Label
        TransducerdistanceEditField     matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        SpeedinMediumEditFieldLabel     matlab.ui.control.Label
        SpeedinMediumEditField          matlab.ui.control.NumericEditField
        cmLabel_3                       matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        TimetosampleEditFieldLabel      matlab.ui.control.Label
        TimetosampleEditField           matlab.ui.control.NumericEditField
        UseTimeQuantCheckBox            matlab.ui.control.CheckBox
        QuantTimeEditFieldLabel         matlab.ui.control.Label
        QuantTimeEditField              matlab.ui.control.NumericEditField
        FastAnalysisCheckBox            matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        CalcOnlyUSFrequencyLabel        matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        UseHadamardCodeCheckBox         matlab.ui.control.CheckBox
        SinglemeasLimitEditFieldLabel   matlab.ui.control.Label
        SinglemeasLimitEditField        matlab.ui.control.EditField
        secLabel_3                      matlab.ui.control.Label
        FittingEnviromentsPanel         matlab.ui.container.Panel
        DCEditFieldLabel                matlab.ui.control.Label
        envDC                           matlab.ui.control.NumericEditField
        USEditFieldLabel                matlab.ui.control.Label
        envUS                           matlab.ui.control.NumericEditField
        KHzLabel_6                      matlab.ui.control.Label
        KHzLabel_7                      matlab.ui.control.Label
        KHzLabel_8                      matlab.ui.control.Label
        ndHarLabel                      matlab.ui.control.Label
        envHar                          matlab.ui.control.NumericEditField
        PeripheralsTab                  matlab.ui.container.Tab
        FunctionGeneratorPanel          matlab.ui.container.Panel
        SclkEditFieldLabel              matlab.ui.control.Label
        SclkEditField                   matlab.ui.control.NumericEditField
        DAQFilterDropDownLabel          matlab.ui.control.Label
        DAQFilterDropDown               matlab.ui.control.DropDown
        TriggerWidthEditFieldLabel      matlab.ui.control.Label
        TriggerWidthEditField           matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        MSsLabel                        matlab.ui.control.Label
        SLabel                          matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        DigitizerPanel                  matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        NumberOfChannelsEditFieldLabel  matlab.ui.control.Label
        NumberOfChannelsEditField       matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        PeriodtoSampleEditFieldLabel    matlab.ui.control.Label
        PeriodtoSampleEditField         matlab.ui.control.NumericEditField
        VppLabel                        matlab.ui.control.Label
        VoltsRangeEditFieldLabel        matlab.ui.control.Label
        VoltsRangeEditField             matlab.ui.control.NumericEditField
        SamplingMethodDropDownLabel     matlab.ui.control.Label
        SamplingMethodDropDown          matlab.ui.control.DropDown
        LaserPanel                      matlab.ui.container.Panel
        nmLabel                         matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        KHzLabel_4                      matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        SpeckleDurationEditFieldLabel   matlab.ui.control.Label
        SpeckleDurationEditField        matlab.ui.control.NumericEditField
        TurnOffAFGButton                matlab.ui.control.Button
        CloseIOPortButton               matlab.ui.control.Button
        AOVarsTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SystemClkTab                    matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        USSignalTab                     matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        SamplesTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TimingTab                       matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        DigitizerTab_2                  matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        LengthTab                       matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        FreqTab                         matlab.ui.container.Tab
        UITable_7                       matlab.ui.control.Table
        PlottingTab                     matlab.ui.container.Tab
        AcoustoOpticsPanel              matlab.ui.container.Panel
        PlotChannelSpinnerLabel         matlab.ui.control.Label
        PlotChannelSpinner              matlab.ui.control.Spinner
        DepthCoorLabel                  matlab.ui.control.Label
        AOZIdxSpinner                   matlab.ui.control.Spinner
        QuantSpinnerLabel               matlab.ui.control.Label
        QuantAOSpinner                  matlab.ui.control.Spinner
        ExternalClkCheckBox             matlab.ui.control.CheckBox
        UltrasoundSignalCheckBox        matlab.ui.control.CheckBox
        FullSignalCheckBox              matlab.ui.control.CheckBox
        MeasuredSamplesCheckBox         matlab.ui.control.CheckBox
        NetSamplesCheckBox              matlab.ui.control.CheckBox
        ReshapedCheckBox_3              matlab.ui.control.CheckBox
        UnfittedDataCheckBox            matlab.ui.control.CheckBox
        PhiCheckBox                     matlab.ui.control.CheckBox
        FittedDataCheckBox              matlab.ui.control.CheckBox
        DemultiplexedCheckBox           matlab.ui.control.CheckBox
        FreqEnviromentEditFieldLabel    matlab.ui.control.Label
        FreqEnviromentEditField         matlab.ui.control.NumericEditField
        KHzLabel_5                      matlab.ui.control.Label
        mmLabel_16                      matlab.ui.control.Label
        dispFullFFTCheckBox             matlab.ui.control.CheckBox
        QuantAvgFFTCheckBox             matlab.ui.control.CheckBox
        DepthAxisasInexCheckBox         matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        PopoutFiguresPanel              matlab.ui.container.Panel
        PopButton                       matlab.ui.control.Button
        FiguresToPopDropDown            matlab.ui.control.DropDown
        ExternalFiguresCheckBox         matlab.ui.control.CheckBox
        ReOpenFiguresCheckBox           matlab.ui.control.CheckBox
        TimeTablePanel                  matlab.ui.container.Panel
        DisplayTimeTableCheckBox        matlab.ui.control.CheckBox
        Scan3DFiguresPanel              matlab.ui.container.Panel
        NavPlainDropDownLabel           matlab.ui.control.Label
        S3DNavPlainDropDown             matlab.ui.control.DropDown
        ReapeatSpinnerLabel             matlab.ui.control.Label
        S3DRepeatSpinner                matlab.ui.control.Spinner
        NavIdxSpinnerLabel              matlab.ui.control.Label
        S3DNavIdxSpinner                matlab.ui.control.Spinner
        mmLabel_14                      matlab.ui.control.Label
        NormalizeColorstoDisplayedPlaneCheckBox  matlab.ui.control.CheckBox
        AxespropertiesPanel             matlab.ui.container.Panel
        XDropDownLabel                  matlab.ui.control.Label
        XDropDown                       matlab.ui.control.DropDown
        YDropDownLabel                  matlab.ui.control.Label
        YDropDown                       matlab.ui.control.DropDown
        ZDropDownLabel                  matlab.ui.control.Label
        ZDropDown                       matlab.ui.control.DropDown
        S2DFiguresPanel                 matlab.ui.container.Panel
        mmLabel_24                      matlab.ui.control.Label
        DepthCoorLabel_2                matlab.ui.control.Label
        S2DCurDepthCoorSpinner          matlab.ui.control.Spinner
        StagesTab                       matlab.ui.container.Tab
        StagesLayoutInSystemPanel       matlab.ui.container.Panel
        DepthAxisDropDownLabel          matlab.ui.control.Label
        DepthAxisDropDown               matlab.ui.control.DropDown
        stage1Label                     matlab.ui.control.Label
        stage2Label                     matlab.ui.control.Label
        stageIDDropDownLabel            matlab.ui.control.Label
        stage1IDDropDown                matlab.ui.control.DropDown
        stageIDDropDown_2Label          matlab.ui.control.Label
        stage2IDDropDown                matlab.ui.control.DropDown
        SetStagesGeometryButton         matlab.ui.control.Button
        TestAssignmentButton            matlab.ui.control.Button
        TestLableLabel                  matlab.ui.control.Label
        Stage1Panel                     matlab.ui.container.Panel
        PositionEditFieldLabel          matlab.ui.control.Label
        Stage1PositionEditField         matlab.ui.control.NumericEditField
        mmLabel_30                      matlab.ui.control.Label
        Stage1MoveToPositionButton      matlab.ui.control.Button
        Stage1Increase                  matlab.ui.control.Button
        Stage1Decrease                  matlab.ui.control.Button
        mmLabel_31                      matlab.ui.control.Label
        Stage1SetStartButton            matlab.ui.control.Button
        Stage1SetEndButton              matlab.ui.control.Button
        Stage1SetStepButton             matlab.ui.control.Button
        StrideEditFieldLabel_5          matlab.ui.control.Label
        Stage1StrideEditField           matlab.ui.control.NumericEditField
        Stage2Panel                     matlab.ui.container.Panel
        PositionEditField_2Label        matlab.ui.control.Label
        Stage2PositionEditField         matlab.ui.control.NumericEditField
        mmLabel_32                      matlab.ui.control.Label
        Stage2MoveToPositionButton      matlab.ui.control.Button
        mmLabel_33                      matlab.ui.control.Label
        Stage2Increase                  matlab.ui.control.Button
        Stage2Decrease                  matlab.ui.control.Button
        Stage2SetStartButton            matlab.ui.control.Button
        Stage2SetEndButton              matlab.ui.control.Button
        Stage2SetStepButton             matlab.ui.control.Button
        StrideEditField_2Label_2        matlab.ui.control.Label
        Stage2StrideEditField           matlab.ui.control.NumericEditField
        stagesTable                     matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        ExportToFileButton              matlab.ui.control.Button
        ExporttoscanDropDownLabel       matlab.ui.control.Label
        ExporttoscanDropDown            matlab.ui.control.DropDown
        CopytoScanButton                matlab.ui.control.Button
        AllStagesHomeButton             matlab.ui.control.Button
        RefreshPositionButton           matlab.ui.control.Button
        acoustoOpticsPlotsTab           matlab.ui.container.TabGroup
        TimeTable                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UITable2_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        PhiTab                          matlab.ui.container.Tab
        axPhi                           matlab.ui.control.UIAxes
        FittedFFTTab                    matlab.ui.container.Tab
        axFittedFFT                     matlab.ui.control.UIAxes
        UnfittedFFTTab                  matlab.ui.container.Tab
        axUnfittedFFT                   matlab.ui.control.UIAxes
        ChFFTTab                        matlab.ui.container.Tab
        axQAvgChFFT                     matlab.ui.control.UIAxes
        USTab                           matlab.ui.container.Tab
        axUsSignal                      matlab.ui.control.UIAxes
        ReshapedTab                     matlab.ui.container.Tab
        axReshape                       matlab.ui.control.UIAxes
        NetTab                          matlab.ui.container.Tab
        axNetSignal                     matlab.ui.control.UIAxes
        DeMulTab                        matlab.ui.container.Tab
        axDeMul                         matlab.ui.control.UIAxes
        MeasSigTab                      matlab.ui.container.Tab
        axMeasSig                       matlab.ui.control.UIAxes
        FullSigTab                      matlab.ui.container.Tab
        axFullSignal                    matlab.ui.control.UIAxes
        ExtClkTab                       matlab.ui.container.Tab
        axExtClk                        matlab.ui.control.UIAxes
        InvisiblePlotTabs               matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
    end


    properties (Access = public)
        %parameters
        importedLimits % Description

        %--------------------------
        % New Variables
        %--------------------------
        
        %objects
        algo;
        ao;
        consistency;
        s3D;
        s2D;
        dv;
        aoStages;
                
        %Graphics Objects Handles
        aoGraphics;
        s2DGraphics;
        s3DGraphics;
        dvGraphics;
        hConsGraph;

        % AO Measurement Vars
        consVars
        
        % Data vars
        algoData;
        aoData;
        s3DData;
        s2DData;
        dvData;
        consData;
        stagesData;
        
        %GUI control Variables
        GUIctrl;
    end
    
    events
        updateQuant
        updateSet
        updateFrame
        
        updatePhiRepEvent
        updatePhiEvent
        
        timeTable
    end
    
    methods (Access = public)
        
        function displayAOTimeTable(app, ~, ~)
%             tic;
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',sum([timeTable.acq timeTable.analyse timeTable.moveData])}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;  
%             toc
        end
    end

    methods (Access = private)

        function applyImportedLimits(app)
             app.S3DFirstStartEditField.Value   = app.importedLimits.startPosX; 
             app.S3DFirstEndEditField.Value     = app.importedLimits.endPosX; 
             app.S3DSecondStartEditField.Value  = app.importedLimits.startPosY; 
             app.S3DSecondEndEditField.Value    = app.importedLimits.endPosY; 
             app.S3DFirstStrideEditField.Value  = app.importedLimits.Xstride; 
             app.S3DSecondStrideEditField.Value = app.importedLimits.Ystride; 
        end
    
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            num = numel(names);
            data = cell(num,3);
            for i=1:num
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end

        % ------------ Consistency GUI Components Update Functions ------------------%
        function updateConsFrameLabel(app)
            app.sLabel_6.Text = sprintf("%.2f[mm]",app.consData.timeFrames(app.FrameToPlotSpinner.Value));
        end

        function setPositionsFromCalib(app, stages)
            app.importedLimits = stages;
        end
        
        function updateConsZLabel(app)
            app.mmLabel_15.Text = sprintf("%.2f[mm]",app.consData.zVecSpinner(app.ZCoorConsSpinner.Value));
        end
    

        %---------------- Algo Functions ---------------%
        function initAlgoVars(app)
           app.algoData.uVars = Algo.uVarsCreate(); 
           app.collectAlgoVars();

           app.algoData.uVars.bufferSizeBytes   = 8*2^20;
           app.algoData.uVars.bytesPerSample    = 2;
           app.algoData.uVars.preTriggerSamples = 14;

        end
        
        function collectAlgoVars(app) 
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value; 
           app.algoData.uVars.fSin            = app.SineFrequencyEditField.Value*1e6;              
           app.algoData.uVars.fTrain          = app.TrainFrequencyEditField.Value*1e3;
           app.algoData.uVars.cycInPulse      = app.SinePeriodsEditField.Value;
           app.algoData.uVars.channels        = app.NumberOfChannelsEditField.Value;
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value;  
           app.algoData.uVars.phantomDepth    = app.PhantomdepthEditField.Value*1e-2;
           app.algoData.uVars.distFromPhantom = app.TransducerdistanceEditField.Value*1e-2;
           app.algoData.uVars.fExtClk         = app.FrequencyEditField.Value*1e6; %fs
           app.algoData.uVars.fSclk           = app.SclkEditField.Value*1e6;
           app.algoData.uVars.extClkDcyc      = app.DutyCycleEditField.Value;
           app.algoData.uVars.useGPU          = false;
           app.algoData.uVars.useQuant        = app.UseTimeQuantCheckBox.Value;
           app.algoData.uVars.quantTime       = app.QuantTimeEditField.Value;
           app.algoData.uVars.useHadamard     = app.UseHadamardCodeCheckBox.Value;
           
           app.algoData.uVars.envDC           = app.envDC.Value*1e3;
           app.algoData.uVars.envUS           = app.envUS.Value*1e3;
           app.algoData.uVars.envHar          = app.envHar.Value*1e3;
           
           if app.TimetosampleEditField.Value > app.ao.limits.measLimit
               app.algoData.uVars.longMeas = true;
               [app.algoData.uVars.timeToSample, ~] = app.ao.calcSplitTimeToSample(uVars.ao.timeToSample);
           else 
               app.algoData.uVars.longMeas = false;
               app.algoData.uVars.timeToSample    = app.TimetosampleEditField.Value;
           end
       end
            
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoData.curRes.extClk);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.usSignal);
            app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.samples);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.timing);
            app.UITable_3.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.digitizer);
            app.UITable_4.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.len);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.freq);
            app.UITable_7.Data = data;
        end
        
        %---------------- Acousto Optics Functions ---------------%       
        % Variables
        function uVars = collectAOVars(app)
            app.aoData.uVars.ao.c                 = app.SpeedinMediumEditField.Value;
            app.aoData.uVars.ao.fSin              = app.SineFrequencyEditField.Value*1e6;              
            app.aoData.uVars.ao.fTrain            = app.TrainFrequencyEditField.Value*1e3;
            app.aoData.uVars.ao.cycInPulse        = app.SinePeriodsEditField.Value; 
            
            app.aoData.uVars.ao.phantomDepth      = app.PhantomdepthEditField.Value*1e-2;
            app.aoData.uVars.ao.distFromPhantom   = app.TransducerdistanceEditField.Value*1e-2;
            app.aoData.uVars.ao.fExtClk           = app.FrequencyEditField.Value*1e6;
            app.aoData.uVars.ao.timeToSample      = app.TimetosampleEditField.Value;
            app.aoData.uVars.ao.extClkDcyc        = app.DutyCycleEditField.Value;
            app.aoData.uVars.ao.fastAnalysis      = app.FastAnalysisCheckBox.Value;

            app.aoData.uVars.ao.envDC             = app.envDC.Value*1e3;
            app.aoData.uVars.ao.envUS             = app.envUS.Value*1e3;
            app.aoData.uVars.ao.envHar            = app.envHar.Value*1e3;
            
            app.aoData.uVars.ao.useQuant          = app.UseTimeQuantCheckBox.Value;
            app.aoData.uVars.ao.quantTime         = app.QuantTimeEditField.Value;
            app.aoData.uVars.ao.useHadamard       = app.UseHadamardCodeCheckBox.Value;
            
            app.aoData.uVars.ao.fSclk             = app.SclkEditField.Value*1e6;
            
            app.aoData.uVars.ao.channels          = app.NumberOfChannelsEditField.Value;
            
            app.aoData.uVars.ao.useGPU            = app.UseGPUCheckBox.Value;
            
            app.collectAOGraphicVars(); 
            
            % set file system properties
            app.collectAOFileSystemVars();
            
            % Set Acousto Optics Object Control Vars
            app.aoData.uVars.ao.useVirtualData   = app.UseVirtualDataCheckBox.Value;
            app.aoData.uVars.ao.limitByN         = app.LimitLiveAOCheckBox.Value;
            app.aoData.uVars.ao.N                = app.NEditField.Value;
            app.aoData.uVars.ao.dispTimeTable    = app.DisplayTimeTableCheckBox.Value;
            
            uVars = app.aoData.uVars;
        end
        
        function fileSystem = collectAOFileSystemVars(app)
            app.aoData.uVars.fileSystem.saveRawData        = app.saveRawDataCheckBox.Value;
            app.aoData.uVars.fileSystem.saveNetSignal      = app.saveNetSignalCheckBox.Value;
            app.aoData.uVars.fileSystem.saveDemultiplexed  = app.saveDeMultiplexedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveReshapedSignal = app.saveReshapedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFFT            = app.saveFFTCheckBox.Value;
            app.aoData.uVars.fileSystem.savePhiChCmplx     = app.saveUSCmplxCheckBox.Value;
            
            app.aoData.uVars.fileSystem.saveResults = app.saveResultsandVarsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFigs    = app.saveFigsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveVars    = app.saveResultsandVarsCheckBox.Value;
            
            app.aoData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.aoData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.aoData.uVars.fileSystem.extProject         = false;
            app.aoData.uVars.fileSystem.extProjPath        = [];
            app.aoData.uVars.fileSystem.extProjResultsPath = [];
            app.aoData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.aoData.uVars.fileSystem;
        end
    
        function figs = collectAOGraphicVars(app)
            % Choose channel and position            
            app.aoData.uVars.figs.ch    = app.PlotChannelSpinner.Value;
            app.aoData.uVars.figs.zIdx  = app.AOZIdxSpinner.Value;
            app.aoData.uVars.figs.quant = app.QuantAOSpinner.Value;

            app.aoData.uVars.figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;
            app.aoData.uVars.figs.plotPhiInd     = app.DepthAxisasInexCheckBox.Value;
            app.aoData.uVars.figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            app.aoData.uVars.figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.aoData.uVars.figs.intExt = 'int';
            else
                app.aoData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            app.aoData.uVars.figs.validStruct.extClk         = app.ExternalClkCheckBox.Value;
            app.aoData.uVars.figs.validStruct.usSignal       = app.UltrasoundSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fullSignal     = app.FullSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.measSamples    = app.MeasuredSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.netSignal      = app.NetSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.deMul          = app.DemultiplexedCheckBox.Value;
            app.aoData.uVars.figs.validStruct.reshapedSignal = app.ReshapedCheckBox_3.Value;
            app.aoData.uVars.figs.validStruct.qAvgChFFT      = app.QuantAvgFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.unFittedFFT    = app.UnfittedDataCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fittedFFT      = app.FittedDataCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phi            = app.PhiCheckBox.Value;
            
            figs = app.aoData.uVars.figs;
            
        end
        
        function figs = collectAOGraphicOwnerVars(app)
            % Choose channel and position
            figs = app.ao.measVars.figs;
            
            figs.ch    = app.PlotChannelSpinner.Value;
            figs.zIdx  = app.AOZIdxSpinner.Value;
            figs.quant = app.QuantAOSpinner.Value;
            
            figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;
            figs.plotPhiInd     = app.DepthAxisasInexCheckBox.Value;
            figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
        end

        function initAOVars(app)
            % These are static variables that shouldn't be changed during operation
            % These variables are initialized and never changed again.
            app.aoData.uVars = acoustoOptics.uVarsCreate();
            
            app.aoData.uVars.ao.IOPort            = 1;
            app.aoData.uVars.ao.IOLine            = 4;

            app.collectAOVars();
            app.collectAOGraphicVars();
            
            app.aoData.uVars.figs.fonts.titleSize  = 14;
            app.aoData.uVars.figs.fonts.labelsSize = 14;
            app.aoData.uVars.figs.fonts.axisSize   = 14;
            
            % Handles
            app.aoData.uVars.figs.extH.extClk.fig         = app.UIFigure;
            app.aoData.uVars.figs.extH.extClk.ax          = app.axExtClk;
            app.aoData.uVars.figs.extH.usSignal.fig       = app.UIFigure;
            app.aoData.uVars.figs.extH.usSignal.ax        = app.axUsSignal;
            app.aoData.uVars.figs.extH.fullSignal.fig     = app.UIFigure;
            app.aoData.uVars.figs.extH.fullSignal.ax      = app.axFullSignal;
            app.aoData.uVars.figs.extH.measSamples.fig    = app.UIFigure;
            app.aoData.uVars.figs.extH.measSamples.ax     = app.axMeasSig;
            app.aoData.uVars.figs.extH.netSignal.fig      = app.UIFigure;
            app.aoData.uVars.figs.extH.netSignal.ax       = app.axNetSignal;
            app.aoData.uVars.figs.extH.deMul.fig          = app.UIFigure;
            app.aoData.uVars.figs.extH.deMul.ax           = app.axDeMul;
            app.aoData.uVars.figs.extH.reshapedSignal.fig = app.UIFigure;
            app.aoData.uVars.figs.extH.reshapedSignal.ax  = app.axReshape;
            app.aoData.uVars.figs.extH.qAvgChFFT.fig      = app.UIFigure;
            app.aoData.uVars.figs.extH.qAvgChFFT.ax       = app.axQAvgChFFT;
            app.aoData.uVars.figs.extH.unFittedFFT.fig    = app.UIFigure;
            app.aoData.uVars.figs.extH.unFittedFFT.ax     = app.axUnfittedFFT;
            app.aoData.uVars.figs.extH.fittedFFT.fig      = app.UIFigure;
            app.aoData.uVars.figs.extH.fittedFFT.ax       = app.axFittedFFT;
            app.aoData.uVars.figs.extH.phi.fig            = app.UIFigure;
            app.aoData.uVars.figs.extH.phi.ax             = app.axPhi;
        end

        function setAOVarsToGUI(app, vars)
            app.SpeedinMediumEditField.Value  = vars.c;
            app.SineFrequencyEditField.Value  = vars.fSin/1e6;             
            app.TrainFrequencyEditField.Value = vars.fTrain/1e3;
            app.SinePeriodsEditField.Value    = vars.cycInPulse; 
            
            app.PhantomdepthEditField.Value       = vars.phantomDepth/1e-2;
            app.TransducerdistanceEditField.Value = vars.distFromPhantom/1e-2;
            app.FrequencyEditField.Value          = vars.fExtClk/1e6;
            app.TimetosampleEditField.Value       = vars.timeToSample;
            app.DutyCycleEditField.Value          = vars.extClkDcyc;
            app.FastAnalysisCheckBox.Value        = vars.fastAnalysis;
             
            app.UseTimeQuantCheckBox.Value    = vars.useQuant;
            app.QuantTimeEditField.Value      = vars.quantTime;
            app.UseHadamardCodeCheckBox.Value = vars.useHadamard;
            
            app.SclkEditField.Value             = vars.fSclk/1e6;
            
            app.NumberOfChannelsEditField.Value = vars.channels;
            
            app.UseGPUCheckBox.Value            = vars.useGPU;
        end
        
        % GUI Control
        function updateAOPlotChSpinnerLimits(app)
            channels = app.aoData.curVars.measVars.algo.digitizer.channels;
            if app.PlotChannelSpinner.Value > channels
                app.PlotChannelSpinner.Value = 1;
            end
            app.PlotChannelSpinner.Limits = [1, channels];
        end
        
        function updateAOPlotZIdxSpinnerLimits(app)
                zVec = app.aoData.curVars.measVars.algo.len.zVecUSRes;
                app.AOZIdxSpinner.Step  = 1;
                if  app.AOZIdxSpinner.Value > length(zVec)
                    app.AOZIdxSpinner.Value = 1;
                    app.updateAOZIdxSpinnerLabel()
                end  
                app.AOZIdxSpinner.Limits = [1, length(zVec)];
        end
        
        function updateQuantSpinnerLimits(app)
            maxQuant = app.aoData.curVars.measVars.algo.samples.numOfQuant;
            if app.QuantAOSpinner.Value > maxQuant
                app.QuantAOSpinner.Value  = 1;
                app.QuantAOSpinner.Step   = 1;
            end
            app.QuantAOSpinner.Limits = [1, maxQuant]; 
        end
        
        function updateAOZIdxSpinnerLabel(app)
            app.mmLabel_16.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.zVecUSRes(app.AOZIdxSpinner.Value)*1e3);
        end
       
        %Figures
        function updateAOFigs(app)
            if ~isempty(app.aoData.curRes)
                figsVars = app.collectAOGraphicOwnerVars();
                app.ao.setFigsVars(figsVars);
            end
        end
        
        %---------------- Scan 2D Functions ---------------%
        % Variables
        function staticVars = uVarsS2DInit(app)
           app.s2DData.uVars = scan2DAO.uVarsCreate();
           
           app.s2DData.uVars.figs.extH.curMainAxis.fig     = app.UIFigure;
           app.s2DData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
           app.s2DData.uVars.figs.extH.curMainAxisAvg.fig  = app.UIFigure;
           app.s2DData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
           app.s2DData.uVars.figs.extH.curMainPlane.fig    = app.UIFigure;
           app.s2DData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
           app.s2DData.uVars.figs.extH.curMainPlaneAvg.fig = app.UIFigure;
           app.s2DData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;

           app.s2DData.uVars.figs.fonts.type = [];
           app.s2DData.uVars.figs.fonts.titleSize  = 14;
           app.s2DData.uVars.figs.fonts.labelsSize = 14;
           app.s2DData.uVars.figs.fonts.axisSize   = 14;

           app.s2DData.uVars.figs.validStruct.curMainAxis     = true;
           app.s2DData.uVars.figs.validStruct.curMainAxisAvg  = true;
           app.s2DData.uVars.figs.validStruct.curMainPlane    = true;
           app.s2DData.uVars.figs.validStruct.curMainPlaneAvg = true;
           
           staticVars.validStruct = app.s2DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s2DData.uVars.figs.intExt;
           staticVars.extH        = app.s2DData.uVars.figs.extH;
        end 
        
        function collectS2DVars(app)         
            % Acousto Optics variables
            app.s2DData.uVars.ao = app.collectAOVars;
            
            % General scan variables
            app.s2DData.uVars.general.externalScan = false;
            
            % Grid scan variables
            app.s2DData.uVars.grid.firstStart  = app.S2DFirstStartEditField.Value;
            app.s2DData.uVars.grid.firstEnd    = app.S2DFirstEndEditField.Value;
            app.s2DData.uVars.grid.firstStride = app.S2DFirstStrideEditField.Value;
            
            app.s2DData.uVars.grid.secondPos  = app.S2DSecondPosEditField.Value;
            
            app.s2DData.uVars.grid.firstAxis  = app.S2DFirstAxisDropDown.Value;
            app.s2DData.uVars.grid.secondAxis = app.S2DSecondAxisDropDown.Value;
            
            app.s2DData.uVars.grid.repeats    = app.S2DRepeatsEditField.Value;
            
            % Stages control variables
            app.s2DData.uVars.stages.stagesOwnedByParent = true;
            app.s2DData.uVars.stages.moveSecondStage = true;
            
            % Figures variables
            app.collectS2DGraphicsVars();
            
            % File system variables
            app.collectS2DFileSystemVars;
        end
        
        function figs = collectS2DGraphicsVars(app)
            app.s2DData.uVars.figs.depthIdx      = app.S2DCurDepthCoorSpinner.Value;
            app.s2DData.uVars.figs.useExtClims   = false;
            app.s2DData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            % Axes Types
            switch app.S2DFirstAxisDropDown.Value
                case 'X'
                    app.s2DData.uVars.figs.firstAxType = app.XDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'Y'
                            app.s2DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                        case 'Z'
                            app.s2DData.uVars.figs.depthAxType = app.YDropDown.Value;
                    end
                case 'Y'
                    app.s2DData.uVars.figs.firstAxType = app.YDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'X'
                            app.s2DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                        case 'Z'
                            app.s2DData.uVars.figs.depthAxType = app.XDropDown.Value;
                    end
                case 'Z'
                    app.s2DData.uVars.figs.firstAxType = app.ZDropDown.Value;
                    switch app.S2DSecondAxisDropDown.Value
                        case 'X'
                            app.s2DData.uVars.figs.depthAxType = app.YDropDown.Value;
                        case 'Y'
                            app.s2DData.uVars.figs.depthAxType = app.XDropDown.Value;
                    end
            end
            
            if app.ExternalFiguresCheckBox.Value
                app.s2DData.uVars.figs.intExt = 'int';
            else
                app.s2DData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.s2DData.uVars.figs;
            
        end
        
        function fileSystem = collectS2DFileSystemVars(app)
            app.s2DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s2DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s2DData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.s2DData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.s2DData.uVars.fileSystem.extProject         = false;
            app.s2DData.uVars.fileSystem.extProjPath        = [];
            app.s2DData.uVars.fileSystem.extProjResultsPath = [];
            app.s2DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s2DData.uVars.fileSystem; 
        end
        
        % GUI
        function updateS2DDepthAxisSpinnerLabel(app)
            idx = app.S2DDepthCoorSpinner.Value;
            app.mmLabel_24.Text = sprintf("%.2f[mm]", app.s2DData.curVars.ao.measVars.algo.len.zVecUSRes(idx)*1e3);
        end
        
        function updateS2DDepthCoorSpinnerLimits(app)
            maxValue = length(app.s2DData.curVars.ao.measVars.algo.len.zVecUSRes);
            app.S2DCurDepthCoorSpinner.LowerLimitInclusive = 'on';
            app.S2DCurDepthCoorSpinner.Step   = 1;
            if app.S2DCurDepthCoorSpinner.Value > maxValue
                app.S2DCurDepthCoorSpinner.Value  = 1;
                app.updateS2DDepthAxisSpinnerLabel()
            end
            app.S2DCurDepthCoorSpinner.Limits = [1, maxValue];
        end 

        function updateS2DGUIComponents(app)
            app.S2DCurDepthCoorSpinner.Enable  = false;
            app.updateS2DDepthCoorSpinnerLimits();
            app.S2DCurDepthCoorSpinner.Enable  = true;
        end

        %---------------- Scan 3D Functions ---------------%
        % Variables
        function staticVars = uVarsS3DInit(app)
           app.s3DData.uVars = scan3DAO.uVarsCreate();
           
           app.s3DData.uVars.figs.extH.nav.fig             = app.UIFigure;
           app.s3DData.uVars.figs.extH.nav.ax              = app.axNav;
           app.s3DData.uVars.figs.extH.navAvg.fig          = app.UIFigure;
           app.s3DData.uVars.figs.extH.navAvg.ax           = app.axNavAvg;
           
           app.s3DData.uVars.figs.fonts.type = [];
           app.s3DData.uVars.figs.fonts.titleSize  = 14;
           app.s3DData.uVars.figs.fonts.labelsSize = 14;
           app.s3DData.uVars.figs.fonts.axisSize   = 14;

           app.s3DData.uVars.figs.validStruct.nav             = true;
           app.s3DData.uVars.figs.validStruct.navAvg          = true;
           
           % Set handles of s2D object;
           app.s3DData.uVars.s2D.figs = app.collectS2DGraphicsVars();
           
           staticVars.validStruct = app.s3DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s3DData.uVars.figs.intExt;
           staticVars.extH        = app.s3DData.uVars.figs.extH;

        end 

        function collectS3DVars(app)         
            % Acousto Optics variables
            app.s3DData.uVars.ao = app.collectAOVars;
            
            % Grid scan variables
            app.s3DData.uVars.grid.firstStart   = app.S3DFirstStartEditField.Value;
            app.s3DData.uVars.grid.firstStride  = app.S3DFirstStrideEditField.Value;
            app.s3DData.uVars.grid.firstEnd     = app.S3DFirstEndEditField.Value;

            app.s3DData.uVars.grid.secondStart  = app.S3DSecondStartEditField.Value;
            app.s3DData.uVars.grid.secondStride = app.S3DSecondStrideEditField.Value;
            app.s3DData.uVars.grid.secondEnd    = app.S3DSecondEndEditField.Value;
            
            app.s3DData.uVars.grid.firstAxis  = app.S3DFirstAxisDropDown.Value;
            app.s3DData.uVars.grid.secondAxis = app.S3DSecondAxisDropDown.Value;
            
            app.s3DData.uVars.grid.repeats = app.S3DRepeatsEditField.Value;
            
            % Stages control variables
            app.s2DData.uVars.stages.stagesOwnedByParent = true;
            
            % Figures variables
            app.collectS3DGraphicsVars();
            
            % File system variables
            app.collectS3DFileSystemVars;
        end
        
        function figs = collectS3DGraphicsVars(app)
            app.s3DData.uVars.figs.depthIdx          = app.S2DCurDepthCoorSpinner.Value;
            app.s3DData.uVars.figs.normColorsToPlane = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DData.uVars.figs.reopenFigures     = app.ReOpenFiguresCheckBox.Value;
            
            % Axes Types
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.s3DData.uVars.figs.firstAxType = app.XDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.YDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.YDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.ZDropDown.Value;
                    end
                case 'Y'
                    app.s3DData.uVars.figs.firstAxType = app.YDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.XDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.XDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.ZDropDown.Value;
                    end
                case 'Z'
                    app.s3DData.uVars.figs.firstAxType = app.ZDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.YDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.XDropDown.Value;
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.XDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.YDropDown.Value;
                    end
            end
            
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.s3DData.uVars.figs.intExt = 'int';
            else
                app.s3DData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.s3DData.uVars.figs;
        end
        
        function setS3DVarsToGUI(app, vars)
            % Grid scan variables
            app.S3DFirstStartEditField.Value   = vars.grid.startFirst;
            app.S3DSecondStartEditField.Value  = vars.grid.startSecond;
            app.S3DFirstEndEditField.Value     = vars.grid.endFirst;
            app.S3DSecondEndEditField.Value    = vars.grid.endSecond;
            app.S3DFirstStrideEditField.Value  = vars.grid.strideFirst;
            app.S3DSecondStrideEditField.Value = vars.grid.strideSecond;
            app.S3DFirstAxisDropDown.Value     = vars.grid.firstAxis;
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Value = 'Y';   
            end
            app.S3DSecondAxisDropDown.Value = vars.grid.secondAxis;
        end
        
        function navVars = collectS3DNavVars(app)
            navVars = scan3DGraphics.createNavigatorVars();
            navVars.navAx  = app.S3DNavPlainDropDown.Value;
            navVars.navIdx = app.S3DNavIdxSpinner.Value;
            navVars.navRep = app.S3DRepeatSpinner.Value;
            app.s3DData.navVars = navVars;
        end
        
        function fileSystem = collectS3DFileSystemVars(app)
            app.s3DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s3DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s3DData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.s3DData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.s3DData.uVars.fileSystem.extProject         = false;
            app.s3DData.uVars.fileSystem.extProjPath        = [];
            app.s3DData.uVars.fileSystem.extProjResultsPath = [];
            app.s3DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s3DData.uVars.fileSystem; 
        end
        
        % GUI Control
        function updateS3DNavIdxSpinnerLimits(app)
           plain = app.S3DNavPlainDropDown.Value;
           app.S3DNavIdxSpinner.LowerLimitInclusive = 'on';
           app.S3DNavIdxSpinner.Step  = 1;
           switch plain
                case 'X'
                    maxLim = length(app.s3DData.curVars.grid.xVec);
                case 'Y'
                    maxLim = length(app.s3DData.curVars.grid.yVec);
                case 'Z'
                    maxLim = length(app.s3DData.curVars.grid.zVec);
           end     
           app.S3DNavIdxSpinner.Value = 1;
           app.updateS3DNavIdxLabel();
           app.S3DNavIdxSpinner.Limits = [1, maxLim];
        end
        
        function updateS3DRepeatsSpinnerLimits(app)
            app.S3DRepeatSpinner.LowerLimitInclusive = 'on';
            app.S3DRepeatSpinner.Step   = 1;
            if app.s3DData.curVars.grid.repeats > 1
               maxRep = app.s3DData.curVars.general.repeats;
               app.S3DRepeatSpinner.Value  = 1;
               app.S3DRepeatSpinner.Limits = [1, maxRep];
               app.S3DRepeatSpinner.Enable = true;
            else
               app.S3DRepeatSpinner.Value  = 1;
               app.S3DRepeatSpinner.Limits = [0,1];
               app.S3DRepeatSpinner.Enable = false;
            end
        end

        function updateS3DNavIdxLabel(app)
           plain = app.S3DNavPlainDropDown.Value;
           idx = app.S3DNavIdxSpinner.Value;
           switch plain
               case 'X'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s3DData.curVars.grid.xVec(idx));
               case 'Y'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s3DData.curVars.grid.yVec(idx));
               case 'Z'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.zVecUSRes(idx)*1e3);
           end
        end
    
        function updateS3DMainAxisZSpinnerLabel(app)
            idx = app.S3DCurDepthCoorSpinner.Value;
            app.mmLabel_19.Text = sprintf("%.2f[mm]", app.s3DData.curVars.ao.measVars.algo.len.zVecUSRes(idx)*1e3);
        end
        
        function updateS3DGUIComponents(app)
            app.S3DNavPlainDropDown.Enable = false;
            app.S3DNavIdxSpinner.Enable    = false;
            app.S3DRepeatSpinner.Enable    = false;

            app.updateS3DRepeatsSpinnerLimits();
            app.updateS3DNavIdxSpinnerLimits();

            app.S3DNavPlainDropDown.Enable = true;
            app.S3DNavIdxSpinner.Enable    = true;

            app.updateS2DGUIComponents();
        end

        %Figures
        function updateS3DNavigator(app)
            navVars = app.collectS3DNavVars();
            app.s3DGraphics.setNavVars(navVars);
            app.s3DGraphics.dispNavPlane();
            app.s3DGraphics.dispNavPlaneAvg();
        end
        
        function updateS3DCurFigures(app)
            idx = app.S3DCurDepthCoorSpinner.Value;
            app.s3DGraphics.setDepthToMainAxis(idx);
            app.s3DGraphics.dispCurMainAxis();
            app.s3DGraphics.dispCurMainAxisAvg();
        end
        
        %---------------- Deep View Functions ---------------%
        % Variables
        function staticVars = uVarsDVInit(app)
           app.s3DData.uVars = scan3DAO.uVarsCreate();
           
%            app.s3DData.uVars.figs.extH.curMainAxis.fig     = app.UIFigure;
%            app.s3DData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
%            app.s3DData.uVars.figs.extH.curMainAxisAvg.fig  = app.UIFigure;
%            app.s3DData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
%            app.s3DData.uVars.figs.extH.curMainPlane.fig    = app.UIFigure;
%            app.s3DData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
%            app.s3DData.uVars.figs.extH.curMainPlaneAvg.fig = app.UIFigure;
%            app.s3DData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;
           app.s3DData.uVars.figs.extH.nav.fig             = app.UIFigure;
           app.s3DData.uVars.figs.extH.nav.ax              = app.axNav;
           app.s3DData.uVars.figs.extH.navAvg.fig          = app.UIFigure;
           app.s3DData.uVars.figs.extH.navAvg.ax           = app.axNavAvg;
           
           app.s3DData.uVars.figs.fonts.type = [];
           app.s3DData.uVars.figs.fonts.titleSize  = 14;
           app.s3DData.uVars.figs.fonts.labelsSize = 14;
           app.s3DData.uVars.figs.fonts.axisSize   = 14;

           app.s3DData.uVars.figs.validStruct.deepView    = true;
           app.s3DData.uVars.figs.validStruct.deepViewLog = true;
           
           % Set handles of s2D object;
           app.s3DData.uVars.s2D.figs = app.collectS2DGraphicsVars();
           
           staticVars.validStruct = app.s3DData.uVars.figs.validStruct;
           staticVars.intExt      = app.s3DData.uVars.figs.intExt;
           staticVars.extH        = app.s3DData.uVars.figs.extH;

        end 

        function collectDVVars(app)         
            % Acousto Optics variables
            app.s3DData.uVars.ao = app.collectAOVars;
            
            % Grid scan variables
            app.s3DData.uVars.grid.firstStart   = app.S3DFirstStartEditField.Value;
            app.s3DData.uVars.grid.firstStride  = app.S3DFirstStrideEditField.Value;
            app.s3DData.uVars.grid.firstEnd     = app.S3DFirstEndEditField.Value;

            app.s3DData.uVars.grid.secondStart  = app.S3DSecondStartEditField.Value;
            app.s3DData.uVars.grid.secondStride = app.S3DSecondStrideEditField.Value;
            app.s3DData.uVars.grid.secondEnd    = app.S3DSecondEndEditField.Value;
            
            app.s3DData.uVars.grid.firstAxis  = app.S3DFirstAxisDropDown.Value;
            app.s3DData.uVars.grid.secondAxis = app.S3DSecondAxisDropDown.Value;
            
            app.s3DData.uVars.grid.repeats = app.S3DRepeatsEditField.Value;
            
            % Stages control variables
            app.s2DData.uVars.stages.stagesOwnedByParent = true;
            
            % Figures variables
            app.collectS3DGraphicsVars();
            
            % File system variables
            app.collectS3DFileSystemVars;
        end
        
        function figs = collectDVGraphicsVars(app)
            app.s3DData.uVars.figs.depthIdx          = app.S2DCurDepthCoorSpinner.Value;
            app.s3DData.uVars.figs.normColorsToPlane = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DData.uVars.figs.reopenFigures     = app.ReOpenFiguresCheckBox.Value;
            
            % Axes Types
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.s3DData.uVars.figs.firstAxType = app.XDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.YDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.YDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.ZDropDown.Value;
                    end
                case 'Y'
                    app.s3DData.uVars.figs.firstAxType = app.YDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.ZDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.XDropDown.Value;
                        case 'Z'
                            app.s3DData.uVars.figs.depthAxType = app.XDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.ZDropDown.Value;
                    end
                case 'Z'
                    app.s3DData.uVars.figs.firstAxType = app.ZDropDown.Value;
                    switch app.S3DSecondAxisDropDown.Value
                        case 'X'
                            app.s3DData.uVars.figs.depthAxType = app.YDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.XDropDown.Value;
                        case 'Y'
                            app.s3DData.uVars.figs.depthAxType = app.XDropDown.Value;
                            app.s3DData.uVars.figs.secondAxType = app.YDropDown.Value;
                    end
            end
            
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.s3DData.uVars.figs.intExt = 'int';
            else
                app.s3DData.uVars.figs.intExt = 'ext';
            end
            
            
            figs = app.s3DData.uVars.figs;
        end
        
        function setDVVarsToGUI(app, vars)
            % Grid scan variables
            app.S3DFirstStartEditField.Value   = vars.grid.startFirst;
            app.S3DSecondStartEditField.Value  = vars.grid.startSecond;
            app.S3DFirstEndEditField.Value     = vars.grid.endFirst;
            app.S3DSecondEndEditField.Value    = vars.grid.endSecond;
            app.S3DFirstStrideEditField.Value  = vars.grid.strideFirst;
            app.S3DSecondStrideEditField.Value = vars.grid.strideSecond;
            app.S3DFirstAxisDropDown.Value     = vars.grid.firstAxis;
            switch app.S3DFirstAxisDropDown.Value
                case 'X'
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Value = 'Y';   
            end
            app.S3DSecondAxisDropDown.Value = vars.grid.secondAxis;
        end
        
        function navVars = collectDVNavVars(app)
            navVars = scan3DGraphics.createNavigatorVars();
            navVars.navAx  = app.S3DNavPlainDropDown.Value;
            navVars.navIdx = app.S3DNavIdxSpinner.Value;
            navVars.navRep = app.S3DRepeatSpinner.Value;
            app.s3DData.navVars = navVars;
        end
        
        function fileSystem = collectDVFileSystemVars(app)
            app.s3DData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.s3DData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.s3DData.uVars.fileSystem.dontSaveVars = false;
            
            app.s3DData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.s3DData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.s3DData.uVars.fileSystem.extProject         = false;
            app.s3DData.uVars.fileSystem.extProjPath        = [];
            app.s3DData.uVars.fileSystem.extProjResultsPath = [];
            app.s3DData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s3DData.uVars.fileSystem; 
        end 

        %---------------- Consistency Functions ---------------%    
%         function uVarsConsisInit(app)
%            app.uVarsConsistency = Consistency.uVarsCreate();
%            
%            app.uVarsConsistency.gReq.extH.allFrames.fig  = app.UIFigure;
%            app.uVarsConsistency.gReq.extH.allFrames.ax   = app.axAllFrames;
%            app.uVarsConsistency.gReq.extH.curFrame.fig   = app.UIFigure;
%            app.uVarsConsistency.gReq.extH.curFrame.ax    = app.axCurFrame;
%            app.uVarsConsistency.gReq.extH.curSet.fig     = app.UIFigure;
%            app.uVarsConsistency.gReq.extH.curSet.ax      = app.axCurSet;
%            app.uVarsConsistency.gReq.extH.navFrame.fig   = app.UIFigure;
%            app.uVarsConsistency.gReq.extH.navFrame.ax    = app.axNavFrame;
%            app.uVarsConsistency.gReq.extH.navSet.fig     = app.UIFigure;
%            app.uVarsConsistency.gReq.extH.navSet.ax      = app.axNavSet;
%            
%         end
%     
%         function uVarsConsisUpdate(app)
%             
%             app.collectAOVars;
%             app.deactivateAOfs();
%             app.uVarsConsistency.acoustoOptics = app.aoData.uVars;
% 
%             app.uVarsConsistency.stages.xPos = app.XpositionEditField.Value;
%             app.uVarsConsistency.stages.yPos = app.YpositionEditField.Value;
%             
%             app.uVarsConsistency.fileSystem.scanName        = app.MeasurementNameEditField.Value;
%             app.uVarsConsistency.fileSystem.resDirPath      = app.DirectoryEditField.Value;
%             app.uVarsConsistency.fileSystem.saveFullData    = false;
%             app.uVarsConsistency.fileSystem.saveReducedData = false;
%             app.uVarsConsistency.fileSystem.saveFigs        = app.saveFigsCheckBox.Value;
%             app.uVarsConsistency.fileSystem.saveResults     = app.saveResultsandVarsCheckBox.Value;
%             
%             app.uVarsConsistency.scan.startTime = app.MinTimeFrameDurationEditField.Value;
%             app.uVarsConsistency.scan.stride    = app.TimeStrideEditField.Value;
%             app.uVarsConsistency.scan.endTime   = app.MaxTimeFrameDurationEditField.Value;
%             app.uVarsConsistency.scan.useQuant  = app.UseTimeQuantCheckBox.Value;
%             app.uVarsConsistency.scan.quantTime = app.QuantTimeEditField.Value;
%             app.uVarsConsistency.scan.numOfSets = app.SetsperTimeFrameEditField.Value;
% 
%             app.uVarsConsistency.gReq.ch    = app.PlotChannelSpinner.Value;
%             app.uVarsConsistency.gReq.zIdx  = app.ZCoorConsSpinner.Value;
%             app.uVarsConsistency.gReq.quant = 1;
%             
%             % Choose internal or external
%             if app.ExternalFiguresCheckBox.Value
%                 app.uVarsConsistency.gReq.intExt = 'int';
%             else
%                 app.uVarsConsistency.gReq.intExt = 'ext';
%             end
% 
%             names = fieldnames(app.uVarsConsistency.gReq.validStruct);
%             for i=1:length(names)
%                 app.uVarsConsistency.gReq.validStruct.(names{i}) = true;
%             end
%         end
 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            f = waitbar(0,'Starting GUI: Resetting Peripherals');
            instrreset;
%             app.AOData.zVec = [];
%             app.AOData.numOfQuants = [];

            %-------- Acousto Optics ---------%
            waitbar(.1,f,'Initing Acousto Optics Object');
            app.initAOVars();
            app.ao = acoustoOptics();
            app.ao.init(app);
            app.aoGraphics = app.ao.getGraphicsHandle();

            app.aoData.importedVars = [];
            app.aoData.importedRes  = [];
            app.aoData.curtVars     = [];
            app.aoData.curRes       = [];
            
%             app.aoListeners{1} = listener(app,'timeTable',@app.displayAOTimeTable); 
            
            app.GUIctrl.restartAO = true;  % to init AO object in the beginning of the first run
            app.GUIctrl.liveAO    = false; % for toggling the button Run Live \ Stop Live
            
            % --------- Algorithm ------- %
            waitbar(.2,f,'Initing Algorithm Object');
            app.initAlgoVars();
            app.algo = Algo();
            
            %-------- Stages ---------%
            waitbar(.3,f,'Connecting to Stages');
            app.aoStages = stages('COM3');
            app.aoStages.connect();
            app.stagesData.avail = app.aoStages.hardwareAvailable;
            app.stagesData.assigned = false;
            if ~app.stagesData.avail
                uiwait(msgbox('NOTICE: No stages Are available'));
                app.stage1IDDropDown.Items = 'No Stages';
                app.stage1IDDropDown.Value = 'No Stages';
                app.stage2IDDropDown.Items = 'No Stages';
                app.stage2IDDropDown.Value = 'No Stages';
                app.stagesData.stage1Id = 0;
                app.stagesData.stage1Ax = 'X';
                app.stagesData.stage2Id = 0;
                app.stagesData.stage2Ax = 'Y';
                app.SetStagesGeometryButton.Enable = false;
            else
                app.stagesData.devicesIDs  = app.aoStages.getDevicesIDs();
                app.stagesData.numOfStages = app.aoStages.vars.numOfStages;
                devicesIdsStr = cell (1,app.stagesData.numOfStages);
                for i = 1:app.stagesData.numOfStages
                    devicesIdsStr{i} = num2str(app.stagesData.devicesIDs(i));
                end
                app.stage1IDDropDown.Items = ['Not Assigned', devicesIdsStr];
                app.stage1IDDropDown.Value = '1';
                app.stage2IDDropDown.Items = ['Not Assigned', devicesIdsStr];
                app.stage2IDDropDown.Value = '2';
                app.stagesData.stage1Id = str2double(app.stage1IDDropDown.Value);
                app.stagesData.stage1Ax = 'X';
                app.stagesData.stage2Id = str2double(app.stage2IDDropDown.Value);
                app.stagesData.stage2Ax = 'Y';
                app.SetStagesGeometryButton.Enable = false;
                
                ax  = [app.stagesData.stage1Ax, app.stagesData.stage2Ax];
                ids = [app.stagesData.stage1Id, app.stagesData.stage2Id];
    
                app.aoStages.assignStagesAxes(ax, ids)
                app.stagesData.assigned = true;
                app.SetStagesGeometryButton.Enable = false;
            end
            app.stagesTable.Data = zeros(4,2);
            
            %-------- Scan 2D --------%
            waitbar(.4,f,'Creatign 2D Scan Object');
            app.uVarsS2DInit();
            app.s2D         = scan2DAO(app.ao, app.aoStages);
            app.s2DGraphics = app.s2D.getGraphicsHandle();
            
            app.s2DData.importedVars = [];
            app.s2DData.importedRes  = [];
            app.s2DData.curtVars     = [];
            app.s2DData.curRes       = [];
            
            % ------- Scan 3D --------%
            waitbar(.5,f,'Creatign 3D Scan Object');
            handles.stages = app.aoStages;
            handles.s2D = app.s2D;
            app.uVarsS3DInit();
            app.s3D            = scan3DAO(handles);
            app.s3DGraphics    = app.s3D.getGraphicsHandle();
            
            app.s3DData.importedVars = [];
            app.s3DData.importedRes  = [];
            app.s3DData.curtVars     = [];
            app.s3DData.curRes       = [];
            
            % ------- Deep View --------%
                        waitbar(.5,f,'Creatign 3D Scan Object');
            app.uVarsS2DInit();
            app.dv            = deepView(app.s2D);
            app.dvGraphics    = app.dv.getGraphicsHandle();
            
            app.dvData.importedVars = [];
            app.dvData.importedRes  = [];
            app.dvData.curtVars     = [];
            app.dvData.curRes       = [];
            
            app.MainAxisTab.Parent          = app.ScanPlotTab;
            app.MainPlaneTab.Parent         = app.ScanPlotTab;
            app.MainAxisAveragedTab.Parent  = app.ScanPlotTab;
            app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
            
            app.NavigatorTab.Parent         = app.ScanPlotTab;
            app.NavigatorAveragedTab.Parent = app.ScanPlotTab;
            
            app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
            
            app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
            app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
            app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
            
            close(f)
        end

        % Button pushed function: AORunButton
        function AORunButtonPushed(app, event)
            if app.stagesData.avail && ~app.AOMeasHereCheckBox.Value && ~app.stagesData.assigned
                uiwait(msgbox('NOTICE: Stages are not assigned!'));
                return
            end
            
            if ~app.AOMeasHereCheckBox.Value
                app.aoStages.moveStageAxisAbs('X', app.AOXpositionEditField.Value)
                app.aoStages.moveStageAxisAbs('Y', app.AOYpositionEditField.Value)
            end
            
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.ao.configPeripherals();
            app.aoData.curVars = app.ao.getVars();

            t = tic;
            app.aoData.curRes    = app.ao.runAcoustoOptics();
            t = toc(t);
            
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',t}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
            dataAO(5,1:2) = {'Data to Graphics', timeTable.setResultsToGraphics};
            dataAO(6,1:2) = {'Plots', timeTable.plotAll};
            dataAO(7,1:2) = {'Save data', timeTable.moveData};
            
            app.UITable2.Data   = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;
        end

        % Button pushed function: AOLiveRunButton
        function AOLiveRunButtonPushed(app, event)
            if app.GUIctrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.runLive = false;
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            else
                if app.GUIctrl.restartAO
                    app.ao.init();
                    app.GUIctrl.restartAO = false;
                end
                app.collectAOVars();
                
                app.ao.setMeasVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                % For spinner update:
                app.aoData.curVars = app.ao.getAOVars();
                %insert function that update the spiner limits
                
                app.AOLiveRunButton.Text = 'Stop Live';
                
                app.GUIctrl.liveAO = true;
                app.aoData.curRes = app.ao.liveAcoustoOptics();
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            end
        end

        % Button pushed function: analysisVarsPathButton
        function analysisVarsPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.VarsEditField.Value = [path, filename]; 
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: analysisDataPathButton
        function analysisDataPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.DataEditField.Value = [path, filename];
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: ImportButton
        function ImporAOResultstButtonPushed(app, event)
            rawDataPath = app.DataEditField.Value;
            varsPath    = app.VarsEditField.Value;
            fprintf("GUI: Trying to load results and vars\n");
            rawData     = app.ao.loadRawDataToAO(rawDataPath);
            vars        = load(varsPath);
            if ~isempty(rawData) && ~isempty(vars) 
                app.AnalyzeOrigButton.Enable = true;
                app.AnalyizeNewButton.Enable = true;
                app.aoData.importedVars = vars;
                app.aoData.importedData = rawData;
                app.setAOVarsToGUI(vars.uVars);
                fprintf("GUI: Successfully loaded results and vars.\n");
            else
                app.AnalyzeOrigButton.Enable = false;
                app.AnalyizeNewButton.Enable = false;
                fprintf("GUI: Invalid files\n");
            end
        end

        % Button pushed function: AnalyzeOrigButton
        function AnalyzeOrigButtonPushed(app, event)
            vars = app.aoData.importedVars;
            
            app.setAOVarsToGUI(app.aoData.importedVars.uVars); %not sure this is convinient to the user
            
            app.collectAOGraphicVars();
            app.collectAOFileSystemVars();
             
            vars.uVars.figs       = app.aoData.uVars.figs; %plot analysed data according to user request
            vars.uVars.fileSystem = app.aoData.uVars.fileSystem; %save analysed data according to user request
            
            app.ao.setMeasVars(vars.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Button pushed function: AnalyizeNewButton
        function AnalyizeNewButtonPushed(app, event)
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Value changed function: AOZIdxSpinner
        function AOZIdxSpinnerValueChanged(app, event)
            app.updateAOZIdxSpinnerLabel();
            app.updateAOFigs();
        end

        % Callback function
        function StagesCalibToolButtonPushed(app, event)
             app.s2dStagesRecon  = true; %TODO: integrate stages calib tool to this gui.
             app.consStagesRecon = true;
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Button pushed function: S3DImportLimitsfromfileButton
        function S3DImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Value changed function: S3DNavPlainDropDown
        function S3DNavPlainDropDownValueChanged(app, event)
            app.updateS3DNavIdxSpinnerLimits();           
            app.updateS3DNavigator();
        end

        % Value changed function: S3DNavIdxSpinner
        function S3DNavIdxSpinnerValueChanged(app, event)
            app.updateS3DNavIdxLabel();
            app.updateS3DNavigator();
        end

        % Value changed function: S3DRepeatSpinner
        function S3DRepeatSpinnerValueChanged(app, event)
            app.updateS3DNavIdxLabel();
            app.updateS3DNavigator();
        end

        % Callback function
        function S3DCurDepthCoorSpinnerValueChanged(app, event)
            app.updateS3DMainAxisZSpinnerLabel();
            app.updateS3DCurFigures();
        end

        % Button pushed function: S3DRunButton
        function S3DRunButtonPushed(app, event)
            app.collectS3DVars();
            
            app.s3D.setUserVars(app.s3DData.uVars);
            app.s3DData.curVars = app.s3D.getVars();
            app.s2DData.curVars = app.s2D.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateS3DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateQuantSpinnerLimits();
            
            app.s3D.configureScan()
            app.s3DData.curRes  = app.s3D.scan3D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Button pushed function: S3DImportResultsButton
        function ImportS2DscanResultsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Results *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded data\n");
                return;
            end
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path,filename]);
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Variables *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded vars\n");
                close(h);
                return;
            end
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path,filename]);
            waitbar(1,h,'Done');
            close(h)

            if isfield(vars, 'uVars')
                app.setS3DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
                app.s3DData.curVars = vars;
                app.s3DData.curData = data;
                vars.uVars.figs = app.collectS3DGraphicsVars();
                app.s3D.setLoaded3DData(data, vars);
                app.updateS3DGUIComponents();
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Value changed function: S3DRepeatsEditField
        function S3DRepeatsEditFieldValueChanged(app, event)
            app.updateRepeatsSpinnerLimits();
        end

        % Button pushed function: dirBrowseButton
        function dirBrowseButtonPushed(app, event)
            app.DirectoryEditField.Value = uigetdir('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            switch(selectedTab.Title)               
                case 'Consistency'
                    app.MainAxisTab.Parent          = app.InvisiblePlotTabs;
                    app.MainPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.MainAxisAveragedTab.Parent  = app.InvisiblePlotTabs;
                    app.MainPlaneAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.ScanPlotTab;
                    app.CurrentFrameTab.Parent      = app.ScanPlotTab;
                    app.FramesNavigatorTab.Parent   = app.ScanPlotTab;
                    
                    app.MeasurementNameEditField.Value = 'Consistency';
                case 'Scan 3D'
                    app.MainAxisTab.Parent          = app.ScanPlotTab;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.ScanPlotTab;
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.NavigatorTab.Parent         = app.ScanPlotTab;
                    app.NavigatorAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    app.MeasurementNameEditField.Value = '2DScan';
                case 'Scan 2D'
                    app.MainAxisTab.Parent          = app.ScanPlotTab;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.ScanPlotTab;
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.InvisiblePlotTabs;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
                    app.MeasurementNameEditField.Value = 'Scan1D';
                case 'Single Measurement'
                    app.MeasurementNameEditField.Value = 'AcoustoOptics';
                case 'Deep View'
                    app.MainAxisTab.Parent          = app.ScanPlotTab;
                    app.MainPlaneTab.Parent         = app.ScanPlotTab;
                    app.MainAxisAveragedTab.Parent  = app.ScanPlotTab;
                    app.MainPlaneAveragedTab.Parent = app.ScanPlotTab;
                    
                    app.NavigatorTab.Parent         = app.InvisiblePlotTabs;
                    app.NavigatorAveragedTab.Parent = app.InvisiblePlotTabs;
                    
                    app.DeepViewPlotTab.Parent      = app.ScanPlotTab;
                    
                    app.ConsistencyPlotTab.Parent   = app.InvisiblePlotTabs;
                    app.CurrentFrameTab.Parent      = app.InvisiblePlotTabs;
                    app.FramesNavigatorTab.Parent   = app.InvisiblePlotTabs;
            end
        end

        % Button pushed function: RunButtonConsistency
        function RunButtonConsistencyPushed(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
            app.uVarsConsisUpdate();
            
            
            if app.initConnsistency
                instrreset
                app.consistency.init();
                app.initConnsistency = false;
            elseif app.consStagesRecon
                instrreset % might be a problem with AFG
                app.consistency.restartStages();
            end
            app.consistency.startScan(app.uVarsConsistency);
        end

        % Value changed function: FrameToPlotSpinner
        function FrameToPlotSpinnerValueChanged(app, event)
            app.updateConsFrameLabel();
            app.updateNavFramePlots();
        end

        % Callback function
        function ZcoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Value changed function: SettoplotSpinner
        function SettoplotSpinnerValueChanged(app, event)
            app.updateNavSetPlots();
        end

        % Callback function
        function ZCoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Selection change function: GlobalParameters
        function GlobalParametersSelectionChanged(app, event)
            selectedTab = app.GlobalParameters.SelectedTab;
            if strcmp(selectedTab.Title, 'Problem Vars')
                app.collectAlgoVars()
                app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
                app.setAlgoValuesTable();
            end
        end

        % Callback function
        function InButtonPushed(app, event)
            zoom(ax, 'on')
        end

        % Callback function
        function ZoomOutButtonPushed(app, event)
            zoom(ax, 'out')
        end

        % Callback function
        function setUpperLimitsButtonPushed(app, event)
        if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
        end
        ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
        if strcmp(ax.Type, 'axes')
            xlim(ax, [app.StartLimitUpper.Value, app.EndLimitUpper.Value]);
        end
        end

        % Callback function
        function setLowerLimitsButtonPushed(app, event)
            ax = app.SingleMeasurementPlots_2.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, [app.StartLimitLower.Value, app.EndLimitLower.Value]);
            end
        end

        % Callback function
        function resetUpperAxLimitsButtonPushed(app, event)
            if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
            end
            ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Callback function
        function resetLowerAxLimitsButtonPushed(app, event)
            if app.ScanPlotTab.Visible
                panel = app.ScanPlotTab;
            elseif app.ConsistencyPlotTab.Visible
                panel = app.ConsistencyPlotTab;
            else
                disp("No visible plot")
                return
            end
            ax = panel.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Value changed function: NumberOfChannelsEditField
        function NumberOfChannelsEditFieldValueChanged(app, event)
            updateChannelPlotSpinner()
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
        end

        % Value changed function: SineFrequencyEditField
        function SineFrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: SinePeriodsEditField
        function SinePeriodsEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: TrainFrequencyEditField
        function TrainFrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner(); 
        end

        % Value changed function: SpeedinMediumEditField
        function SpeedinMediumEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanged(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changing function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanging(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changed function: QuantAOSpinner
        function QuantAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: PlotChannelSpinner
        function ChannelAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: FreqEnviromentEditField
        function FreqEnviromentAOEditFieldValueChanged(app, event)
            app.updateAOFigs();
        end

        % Callback function
        function ImportResultsButtonPushed(app, event)
            [path] = uigetdir('D:\Results\');
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path, '\Results']);
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path, '\Vars']);
            waitbar(1,h,'Done');
            close(h)
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            
            app.updateS3DGeneralData(...
                    vars.curVars.acoustoOptics.algoVars.len.zVecUSRes,...
                    vars.curVars.stages.xVec,...
                    vars.curVars.stages.yVec,...
                    vars.curVars.scan.repeats,...
                    vars.curVars.scan.numOfQuant);
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            notify(app, 'updatePhiRepEvent');
            notify(app, 'updatePhiEvent');
            
        end

        % Button pushed function: CloseIOPortButton
        function CloseIOPortButtonPushed(app, event)
            app.ao.IO.close();
            fprintf("Closing IO\n")
        end

        % Button pushed function: TurnOffAFGButton
        function TurnOffAFGButtonPushed(app, event)
            app.ao.fGen.disableAllOutputs();
        end

        % Value changed function: LimitLiveAOCheckBox
        function LimitLiveAOCheckBoxValueChanged(app, event)
            value = app.LimitLiveAOCheckBox.Value;
            if value
                app.NEditField.Enable = true;
            else
                app.NEditField.Enable = false;
            end
        end

        % Button pushed function: ImportVarsButton
        function ImportVarsToGUIButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOVarsToGUI(vars.uVars); 
            else
                app.setAOVarsToGUI(vars);
            end
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsFromGUIButtonPushed(app, event)
            app.collectAOVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.aoData.uVars;
            vars.uVars.figs = [];
            [filename, path] = uiputfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Value changed function: AOMeasHereCheckBox
        function AOMeasHereCheckBoxValueChanged(app, event)
            value = app.AOMeasHereCheckBox.Value;
            if value
                app.AOXpositionEditField.Enable = false;
                app.AOYpositionEditField.Enable = false;
            else
                app.AOXpositionEditField.Enable = true;
                app.AOYpositionEditField.Enable = true;
            end
        end

        % Value changed function: SamplingMethodDropDown
        function SamplingMethodDropDownValueChanged(app, event)
            value = app.SamplingMethodDropDown.Value;
            if strcmp(value ,'TS')
                set(app.TimetosampleEditField,'Enable','on');
                set(app.secLabel,'Enable','on');
                set(app.PeriodtoSampleEditField,'Enable','off');
                set(app.Label_2,'Enable','off');
            else
                set(app.TimetosampleEditField,'Enable','off');
                set(app.secLabel,'Enable','off');
                set(app.PeriodtoSampleEditField,'Enable','on');
                set(app.Label_2,'Enable','on');           
            end
        end

        % Button pushed function: S3DExportScanVarsButton
        function S3DExportScanVarsButtonPushed(app, event)
            app.collectS3DVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.s2dData.uVars;
            vars.uVars.figs = [];
            vars.uVars.ao.figs = [];
            [filename, path] = uiputfile('*.mat', 'Save Vars As', 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Button pushed function: S3DImportScanVarsButton
        function S3DImportScanVarsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setS2DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Callback function
        function S1DScanAxisDropDownValueChanged(app, event)
            switch app.S1DScanAxisDropDown.Value
                case 'X'
                    app.S1DDepthAxisDropDown.Items = {'Y', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Y'};
                    app.S1DDepthAxisDropDown.Value = 'Y';   
            end  
        end

        % Value changed function: S3DFirstAxisDropDown
        function S3DFirstAxisDropDownValueChanged(app, event)
            value = app.S3DFirstAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S3DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DSecondAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S3DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DSecondAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S3DSecondAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S3DSecondAxisDropDown.Value = 'X';
                     end
            end            
        end

        % Value changed function: S3DSecondAxisDropDown
        function S3DSecondAxisDropDownValueChanged(app, event)
            value = app.S3DSecondAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S3DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DFirstAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S3DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S3DFirstAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S3DFirstAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S3DFirstAxisDropDown.Value = 'X';
                     end
            end
        end

        % Callback function
        function Perform2DscanCheckBoxValueChanged(app, event)
            value = app.Perform3DscanCheckBox.Value;
            if value
                app.S3DSecondStrideEditField.Enable = false;
                app.S3DSecondEndEditField.Enable = false;        
            else 
                app.S3DSecondStrideEditField.Enable = true;
                app.S3DSecondEndEditField.Enable = true; 
            end
        end

        % Value changed function: 
        % NormalizeColorstoDisplayedPlaneCheckBox
        function NormalizeColorstoDisplayedPlaneCheckBoxValueChanged(app, event)
            value = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s3DGraphics.setColorsScale(value);
            app.updateS3DNavigator();
        end

        % Value changed function: DepthAxisDropDown
        function DepthAxisDropDownValueChanged(app, event)
            value = app.DepthAxisDropDown.Value;
            switch value
                case 'X'
                    app.stage1Label.Text = 'Y';
                    app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'Y';
                    app.stagesData.stage2Ax = 'Z';
                    
                    app.S2DFirstAxisDropDown.Items  = {'Y', 'Z'};
                    app.S2DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S3DFirstAxisDropDown.Items  = {'Y', 'Z'};
                    app.S3DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.DVFirstAxisDropDown.Items   = {'Y', 'Z'};
                    app.DVSecondAxisDropDown.Items  = {'Y', 'Z'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'Y';
                    app.S2DSecondAxisDropDown.Value = 'Z';
                    app.S3DFirstAxisDropDown.Value  = 'Y';
                    app.S3DSecondAxisDropDown.Value = 'Z';
                    app.DVFirstAxisDropDown.Value   = 'Y';
                    app.DVSecondAxisDropDown.Value  = 'Z';
                    
                case 'Y'
                    app.stage1Label.Text = 'X';
                    app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Z';
                    
                    app.S2DFirstAxisDropDown.Items  = {'X', 'Z'};
                    app.S2DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S3DFirstAxisDropDown.Items  = {'X', 'Z'};
                    app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.DVFirstAxisDropDown.Items   = {'X', 'Z'};
                    app.DVSecondAxisDropDown.Items  = {'X', 'Z'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'X';
                    app.S2DSecondAxisDropDown.Value = 'Z';
                    app.S3DFirstAxisDropDown.Value  = 'X';
                    app.S3DSecondAxisDropDown.Value = 'Z';
                    app.DVFirstAxisDropDown.Value   = 'X';
                    app.DVSecondAxisDropDown.Value  = 'Z';
                    
                case 'Z'
                    app.stage1Label.Text = 'X';
                    app.stage2Label.Text = 'Y';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Y';
                    
                    app.S2DFirstAxisDropDown.Items  = {'X', 'Y'};
                    app.S2DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S3DFirstAxisDropDown.Items  = {'X', 'Y'};
                    app.S3DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.DVFirstAxisDropDown.Items   = {'X', 'Y'};
                    app.DVSecondAxisDropDown.Items  = {'X', 'Y'};
                    
                    app.S2DFirstAxisDropDown.Value  = 'X';
                    app.S2DSecondAxisDropDown.Value = 'Y';
                    app.S3DFirstAxisDropDown.Value  = 'X';
                    app.S3DSecondAxisDropDown.Value = 'Y';
                    app.DVFirstAxisDropDown.Value   = 'X';
                    app.DVSecondAxisDropDown.Value  = 'Y';
            end
            
            app.stage1IDDropDown.Value = 'Not Assigned';
            app.stage2IDDropDown.Value = 'Not Assigned';
            app.stagesData.stage1Id = 0;
            app.stagesData.stage2Id = 0;
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Button pushed function: SetStagesGeometryButton
        function SetStagesGeometryButtonPushed(app, event)
            if ~app.stagesData.stage1Id || ~app.stagesData.stage2Id
                fprintf("Can't Assign arbitrary stage");
                return
            end
            
            ax  = [app.stagesData.stage1Ax, app.stagesData.stage2Ax];
            ids = [app.stagesData.stage1Id, app.stagesData.stage2Id];

            app.aoStages.assignStagesAxes(ax, ids)
            app.stagesData.assigned = true;
            app.SetStagesGeometryButton.Enable = false;
            
        end

        % Value changed function: stage1IDDropDown
        function stage1IDDropDownValueChanged(app, event)
            value = app.stage1IDDropDown.Value;
            if strcmp(value, 'Not Assigned')
                app.stagesData.stage1Id = 0;
            else
                app.stagesData.stage1Id = str2double(value);
            end
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Value changed function: stage2IDDropDown
        function stage2IDDropDownValueChanged(app, event)
            value = app.stage2IDDropDown.Value;
            if strcmp(value, 'Not Assigned')
                app.stagesData.stage2Id = 0;
            else
                app.stagesData.stage2Id = str2double(value);
            end
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Button pushed function: TestAssignmentButton
        function TestAssignmentButtonPushed(app, event)
            app.TestLableLabel.Visible = true;
            if ~app.stagesData.stage1Id
                app.TestLableLabel.Text = sprintf("Stage %s is N/A.", app.stagesData.stage1Ax);
            else
                app.TestLableLabel.Text = sprintf("Stage %s is moving.", app.stagesData.stage1Ax);
                app.aoStages.moveStagesForIdentification(app.stagesData.stage1Id);
            end
            
            if ~app.stagesData.stage2Id
                app.TestLableLabel.Text = sprintf("Stage %s is N/A.", app.stagesData.stage2Ax);
            else
                app.TestLableLabel.Text = sprintf("Stage %s is moving.", app.stagesData.stage2Ax);
                app.aoStages.moveStagesForIdentification(app.stagesData.stage2Id);
            end
            app.TestLableLabel.Visible = false;
        end

        % Button pushed function: S2DRunButton
        function S2DRunButtonPushed(app, event)
            app.collectS2DVars();
            
            app.s2D.setUserVars(app.s2DData.uVars);
            app.s2DData.curVars = app.s2D.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateS2DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateQuantSpinnerLimits();
            
            app.s2D.configureScan();
            app.s2DData.curRes  = app.s2D.scan2D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Value changed function: SinglemeasLimitEditField
        function SinglemeasLimitEditFieldValueChanged(app, event)
            value = app.SinglemeasLimitEditField.Value;
            app.ao.limits.measLimit = value;
        end

        % Value changed function: DepthAxisasInexCheckBox
        function DepthAxisasInexCheckBoxValueChanged(app, event)
            app.updateAOFigs();
        end

        % Button pushed function: PopButton
        function PopButtonPushed(app, event)
            switch app.FiguresToPopDropDown.Value
                case 'Acousto Optics'
                    if app.aoData.uVars.figs.validStruct.extClk
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axExtClk,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.usSignal 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axUsSignal,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.fullSignal 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axFullSignal,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.measSamples
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axMeasSig,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.netSignal
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axNetSignal,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.deMul 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axDeMul,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.reshapedSignal 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axReshape,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.qAvgChFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axQAvgChFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.unFittedFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axUnfittedFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.fittedFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axFittedFFT,ax)
                    end
                    if app.aoData.uVars.figs.validStruct.phi
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.axPhi,ax)
                    end
                case 'Scan 2D'
                    
                case 'Scan 3D'
                
                case 'Deep View'
                    
            end
        end

        % Value changed function: S2DFirstAxisDropDown
        function S2DFirstAxisDropDownValueChanged(app, event)
            value = app.S2DFirstAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S2DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S2DSecondAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S2DSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S2DSecondAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S2DSecondAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S2DSecondAxisDropDown.Value = 'X';
                     end
            end
        end

        % Value changed function: S2DSecondAxisDropDown
        function S2DSecondAxisDropDownValueChanged(app, event)
            value = app.S2DSecondAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.S2DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S2DFirstAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.S2DFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.S2DFirstAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.S2DFirstAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.S2DFirstAxisDropDown.Value = 'X';
                     end
            end
        end

        % Button pushed function: Stage1MoveToPositionButton
        function Stage1MoveToPositionButtonPushed(app, event)
            pos = app.Stage1PositionEditField.Value;
            app.aoStages.moveStageAbsId(1, pos);
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Button pushed function: Stage2MoveToPositionButton
        function Stage2MoveToPositionButtonPushed(app, event)
            pos = app.Stage2PositionEditField.Value;
            app.aoStages.moveStageAbsId(2, pos);
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Button pushed function: Stage1Increase
        function Stage1IncreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.aoStages.moveStageRelId(1, step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Button pushed function: Stage2Increase
        function Stage2IncreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.aoStages.moveStageRelId(2, step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;            
        end

        % Button pushed function: Stage1Decrease
        function Stage1DecreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.aoStages.moveStageRelId(1, -step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Button pushed function: Stage2Decrease
        function Stage2DecreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.aoStages.moveStageRelId(2, -step)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Button pushed function: Stage1SetStartButton
        function Stage1SetStartButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(2,1) = pos(1);
        end

        % Button pushed function: Stage2SetStartButton
        function Stage2SetStartButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(2,2) = pos(2);
        end

        % Button pushed function: Stage1SetStepButton
        function Stage1SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,1) = app.Stage1StrideEditField.Value;
        end

        % Button pushed function: Stage2SetStepButton
        function Stage2SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,2) = app.Stage2StrideEditField.Value;
        end

        % Button pushed function: Stage1SetEndButton
        function Stage1SetEndButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(4,1) = pos(1);
        end

        % Button pushed function: Stage2SetEndButton
        function Stage2SetEndButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(4,2) = pos(2);
        end

        % Button pushed function: AllStagesHomeButton
        function AllStagesHomeButtonPushed(app, event)
            app.aoStages.moveHome();
        end

        % Button pushed function: ExportToFileButton
        function ExportToFileButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Save Stages Configurations');

            confs.stage1Ax = app.stagesData.stage1Ax;
            confs.stage2Ax = app.stagesData.stage2Ax;
            
            confs.startPos1 = app.stagesTable.Data(2,1);
            confs.startPos2 = app.stagesTable.Data(2,2);
            confs.endPos1   = app.stagesTable.Data(3,1);
            confs.endPos2   = app.stagesTable.Data(3,2);
            confs.stride1   = app.stagesTable.Data(4,1);
            confs.stride2   = app.stagesTable.Data(4,2);

            save([path, file], '-struct','confs');
        end

        % Button pushed function: CopytoScanButton
        function CopytoScanButtonPushed(app, event)
            value = app.ExporttoscanDropDown.Value;
            switch(value)
                case 'Scan 2D'
                    if strcmp(app.S2DFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.S2DFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S2DFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S2DFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                        app.S2DSecondPosEditField.Value   = app.stagesTable.Data(2,2);
                    else
                        app.S2DFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S2DFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S2DFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                        app.S2DSecondPosEditField.Value   = app.stagesTable.Data(2,1);
                    end
                case 'Scan 3D'
                    if strcmp(app.S3DFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.S3DFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S3DFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S3DFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                                                
                        app.S3DSecondStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S3DSecondStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S3DSecondEndEditField.Value    = app.stagesTable.Data(4,2);
                    else
                        app.S3DFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.S3DFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.S3DFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                                                
                        app.S3DSecondStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.S3DSecondStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.S3DSecondEndEditField.Value    = app.stagesTable.Data(4,1);
                    end
                    
                case 'Deep View'
                    if strcmp(app.DVFirstAxisDropDown.Value, app.stagesData.stage1Ax)
                        app.DVFirstStartEditField.Value  = app.stagesTable.Data(2,1);
                        app.DVFirstStrideEditField.Value = app.stagesTable.Data(3,1);
                        app.DVFirstEndEditField.Value    = app.stagesTable.Data(4,1);
                        app.DVSecondPosEditField.Value   = app.stagesTable.Data(2,2);
                    else
                        app.DVFirstStartEditField.Value  = app.stagesTable.Data(2,2);
                        app.DVFirstStrideEditField.Value = app.stagesTable.Data(3,2);
                        app.DVFirstEndEditField.Value    = app.stagesTable.Data(4,2);
                        app.DVSecondPosEditField.Value   = app.stagesTable.Data(2,1);
                    end 
            end
        end

        % Button pushed function: RefreshPositionButton
        function RefreshPositionButtonPushed(app, event)
            pos = app.aoStages.getPosition();
            app.stagesTable.Data(1,1:2) = pos;
        end

        % Value changed function: ExporttoscanDropDown
        function ExporttoscanDropDownValueChanged(app, event)
            
            
        end

        % Value changed function: DVFirstAxisDropDown
        function DVFirstAxisDropDownValueChanged(app, event)
            value = app.DVFirstAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.DVSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVSecondAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.DVSecondAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVSecondAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.DVSecondAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.DVSecondAxisDropDown.Value = 'X';
                     end
            end
            
        end

        % Value changed function: DVSecondAxisDropDown
        function DVSecondAxisDropDownValueChanged(app, event)
            value = app.DVSecondAxisDropDown.Value;
            switch app.DepthAxisDropDown.Value
                case 'X' 
                     switch value
                         case 'Y'
                             app.DVFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVFirstAxisDropDown.Value = 'Y';
                     end
                 case 'Y' 
                     switch value
                         case 'X'
                             app.DVFirstAxisDropDown.Value = 'Z';
                         case 'Z' 
                             app.DVFirstAxisDropDown.Value = 'X';
                     end
                 case 'Z' 
                     switch value
                         case 'X'
                             app.DVFirstAxisDropDown.Value = 'Y';
                         case 'Y' 
                             app.DVFirstAxisDropDown.Value = 'X';
                     end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.UIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.UIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.ScanPlotTab = uitabgroup(app.UIFigure);
            app.MainAxisTab = uitab(app.ScanPlotTab);
            app.MainAxisTab.Title = 'Main Axis';
            app.axMainAxis = uiaxes(app.MainAxisTab);
            title(app.axMainAxis, 'Title')
            xlabel(app.axMainAxis, 'X')
            ylabel(app.axMainAxis, 'Y')
            app.MainPlaneTab = uitab(app.ScanPlotTab);
            app.MainPlaneTab.Title = 'Main Plain';
            app.axMainPlane = uiaxes(app.MainPlaneTab);
            title(app.axMainPlane, 'Title')
            xlabel(app.axMainPlane, 'X')
            ylabel(app.axMainPlane, 'Y')
            app.NavigatorTab = uitab(app.ScanPlotTab);
            app.NavigatorTab.Title = 'Navigator';
            app.axNav = uiaxes(app.NavigatorTab);
            title(app.axNav, 'Title')
            xlabel(app.axNav, 'X')
            ylabel(app.axNav, 'Y')
            app.MainAxisAveragedTab = uitab(app.ScanPlotTab);
            app.MainAxisAveragedTab.Title = 'Main Axis (Averaged)';
            app.axMainAxisAvg = uiaxes(app.MainAxisAveragedTab);
            title(app.axMainAxisAvg, 'Title')
            xlabel(app.axMainAxisAvg, 'X')
            ylabel(app.axMainAxisAvg, 'Y')
            app.MainPlaneAveragedTab = uitab(app.ScanPlotTab);
            app.MainPlaneAveragedTab.Title = 'Main Plain (Averaged)';
            app.axMainPlaneAvg = uiaxes(app.MainPlaneAveragedTab);
            title(app.axMainPlaneAvg, 'Title')
            xlabel(app.axMainPlaneAvg, 'X')
            ylabel(app.axMainPlaneAvg, 'Y')
            app.NavigatorAveragedTab = uitab(app.ScanPlotTab);
            app.NavigatorAveragedTab.Title = 'Navigator (Averaged)';
            app.axNavAvg = uiaxes(app.NavigatorAveragedTab);
            title(app.axNavAvg, 'Title')
            xlabel(app.axNavAvg, 'X')
            ylabel(app.axNavAvg, 'Y')
            app.DeepViewPlotTab = uitab(app.ScanPlotTab);
            app.DeepViewPlotTab.Title = 'Deep View';
            app.axDeepView = uiaxes(app.DeepViewPlotTab);
            title(app.axDeepView, 'Title')
            xlabel(app.axDeepView, 'X')
            ylabel(app.axDeepView, 'Y')
            app.ConsistencyPlotTab = uitab(app.ScanPlotTab);
            app.ConsistencyPlotTab.Title = 'Consistency';
            app.axConsistency = uiaxes(app.ConsistencyPlotTab);
            title(app.axConsistency, 'Title')
            xlabel(app.axConsistency, 'X')
            ylabel(app.axConsistency, 'Y')
            app.CurrentFrameTab = uitab(app.ScanPlotTab);
            app.CurrentFrameTab.Title = 'Current Frame';
            app.axCurFrame = uiaxes(app.CurrentFrameTab);
            title(app.axCurFrame, 'Title')
            xlabel(app.axCurFrame, 'X')
            ylabel(app.axCurFrame, 'Y')
            app.FramesNavigatorTab = uitab(app.ScanPlotTab);
            app.FramesNavigatorTab.Title = 'Frames Navigator';
            app.axFrameNav = uiaxes(app.FramesNavigatorTab);
            title(app.axFrameNav, 'Title')
            xlabel(app.axFrameNav, 'X')
            ylabel(app.axFrameNav, 'Y')
            app.SimulationSavePanel = uipanel(app.UIFigure);
            app.SimulationSavePanel.Title = 'Simulation Name';
            app.MeasurementNameEditFieldLabel = uilabel(app.SimulationSavePanel);
            app.MeasurementNameEditFieldLabel.Text = 'Measurement Name';
            app.MeasurementNameEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.MeasurementNameEditField.Value = 'Scan';
            app.DirectoryEditFieldLabel = uilabel(app.SimulationSavePanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.DirectoryEditField.Value = 'D:\Results';
            app.saveResultsandVarsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveResultsandVarsCheckBox.Text = 'Save Results and Vars';
            app.saveFigsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFigsCheckBox.Text = 'Save Figs';
            app.saveRawDataCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveRawDataCheckBox.Text = 'Raw Data';
            app.saveNetSignalCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveNetSignalCheckBox.Text = 'Net Signal';
            app.saveDeMultiplexedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveDeMultiplexedCheckBox.Text = 'DeMultiplexed';
            app.saveReshapedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveReshapedCheckBox.Text = 'Reshaped';
            app.saveFFTCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFFTCheckBox.Text = 'FFT';
            app.SaveSpecificDataLabel = uilabel(app.SimulationSavePanel);
            app.SaveSpecificDataLabel.Text = 'Save Specific Data:';
            app.saveUSCmplxCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveUSCmplxCheckBox.Text = 'US Cmplx.';
            app.dirBrowseButton = uibutton(app.SimulationSavePanel, 'push');
            app.dirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dirBrowseButtonPushed, true);
            app.dirBrowseButton.Text = '...';
            app.MeasMethod = uitabgroup(app.UIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.AORunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.AORunButton.ButtonPushedFcn = createCallbackFcn(app, @AORunButtonPushed, true);
            app.AORunButton.Text = 'Run';
            app.AOLiveRunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.AOLiveRunButton.ButtonPushedFcn = createCallbackFcn(app, @AOLiveRunButtonPushed, true);
            app.AOLiveRunButton.Text = 'Run Live';
            app.StagesPanel_3 = uipanel(app.SingleMeasurementTab);
            app.StagesPanel_3.Title = 'Stages';
            app.mmLabel_17 = uilabel(app.StagesPanel_3);
            app.mmLabel_17.Text = '[mm]';
            app.mmLabel_18 = uilabel(app.StagesPanel_3);
            app.mmLabel_18.Text = '[mm]';
            app.XpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.XpositionEditField_2Label.Enable = 'off';
            app.XpositionEditField_2Label.Text = 'X position';
            app.AOXpositionEditField = uieditfield(app.StagesPanel_3, 'numeric');
            app.AOXpositionEditField.Enable = 'off';
            app.AOXpositionEditField.Value = 100;
            app.YpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.YpositionEditField_2Label.Enable = 'off';
            app.YpositionEditField_2Label.Text = 'Y position';
            app.AOYpositionEditField = uieditfield(app.StagesPanel_3, 'numeric');
            app.AOYpositionEditField.Enable = 'off';
            app.AOYpositionEditField.Value = 86.5;
            app.AOMeasHereCheckBox = uicheckbox(app.StagesPanel_3);
            app.AOMeasHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @AOMeasHereCheckBoxValueChanged, true);
            app.AOMeasHereCheckBox.Text = 'Measure at current pos';
            app.AOMeasHereCheckBox.Value = true;
            app.LiveAcoustoOpticsPanel = uipanel(app.SingleMeasurementTab);
            app.LiveAcoustoOpticsPanel.Title = 'Live Acousto Optics';
            app.LimitLiveAOCheckBox = uicheckbox(app.LiveAcoustoOpticsPanel);
            app.LimitLiveAOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitLiveAOCheckBoxValueChanged, true);
            app.LimitLiveAOCheckBox.Text = 'LimitLiveAO';
            app.LimitLiveAOCheckBox.Value = true;
            app.NEditFieldLabel = uilabel(app.LiveAcoustoOpticsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LiveAcoustoOpticsPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 1;
            app.ImportsavedResultsPanel = uipanel(app.SingleMeasurementTab);
            app.ImportsavedResultsPanel.Title = 'Import saved Results';
            app.DataEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.DataEditField.Editable = 'off';
            app.analysisDataPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisDataPathButtonPushed, true);
            app.analysisDataPathButton.Text = '...';
            app.AnalyzeOrigButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyzeOrigButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeOrigButtonPushed, true);
            app.AnalyzeOrigButton.Enable = 'off';
            app.AnalyzeOrigButton.Tooltip = {'Analyze loaded data with original variables.'};
            app.AnalyzeOrigButton.Text = 'Analyze Orig';
            app.VarsEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.VarsEditFieldLabel.Text = 'Vars';
            app.VarsEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.VarsEditField.Editable = 'off';
            app.analysisVarsPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisVarsPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisVarsPathButtonPushed, true);
            app.analysisVarsPathButton.Text = '...';
            app.ImportButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImporAOResultstButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AnalyizeNewButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyizeNewButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyizeNewButtonPushed, true);
            app.AnalyizeNewButton.Enable = 'off';
            app.AnalyizeNewButton.Tooltip = {'Analyse with variables appear in this GUI'};
            app.AnalyizeNewButton.Text = 'Analyize New';
            app.UseVirtualDataCheckBox = uicheckbox(app.ImportsavedResultsPanel);
            app.UseVirtualDataCheckBox.Text = 'Use Virtual Data';
            app.ImportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ImportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVarsToGUIButtonPushed, true);
            app.ImportVarsButton.Text = 'Import Vars';
            app.ExportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsFromGUIButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.Scan2DTab = uitab(app.MeasMethod);
            app.Scan2DTab.Title = 'Scan 2D';
            app.S2DGridPanel = uipanel(app.Scan2DTab);
            app.S2DGridPanel.Title = 'Stages';
            app.S2DFirstScanAxisLabel = uilabel(app.S2DGridPanel);
            app.S2DFirstScanAxisLabel.Text = 'First Scan Axis:';
            app.AxisDropDownLabel_2 = uilabel(app.S2DGridPanel);
            app.AxisDropDownLabel_2.Text = 'Axis';
            app.S2DFirstAxisDropDown = uidropdown(app.S2DGridPanel);
            app.S2DFirstAxisDropDown.Items = {'Y', 'X'};
            app.S2DFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S2DFirstAxisDropDownValueChanged, true);
            app.S2DFirstAxisDropDown.Value = 'X';
            app.mmLabel_20 = uilabel(app.S2DGridPanel);
            app.mmLabel_20.Text = '[mm]';
            app.StartEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.StartEditFieldLabel_2.Text = 'Start';
            app.S2DFirstStartEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstStartEditField.Value = 102;
            app.mmLabel_21 = uilabel(app.S2DGridPanel);
            app.mmLabel_21.Text = '[mm]';
            app.StrideEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.StrideEditFieldLabel_2.Text = 'Stride';
            app.S2DFirstStrideEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstStrideEditField.Value = -1;
            app.mmLabel_22 = uilabel(app.S2DGridPanel);
            app.mmLabel_22.Text = '[mm]';
            app.EndEditFieldLabel_2 = uilabel(app.S2DGridPanel);
            app.EndEditFieldLabel_2.Text = 'End';
            app.S2DFirstEndEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DFirstEndEditField.Value = 95;
            app.SecondScanAxisLabel = uilabel(app.S2DGridPanel);
            app.SecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label_2 = uilabel(app.S2DGridPanel);
            app.AxisDropDown_2Label_2.Text = 'Axis';
            app.S2DSecondAxisDropDown = uidropdown(app.S2DGridPanel);
            app.S2DSecondAxisDropDown.Items = {'Y', 'Z'};
            app.S2DSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S2DSecondAxisDropDownValueChanged, true);
            app.S2DSecondAxisDropDown.Value = 'Y';
            app.mmLabel_23 = uilabel(app.S2DGridPanel);
            app.mmLabel_23.Text = '[mm]';
            app.PosLabel = uilabel(app.S2DGridPanel);
            app.PosLabel.Text = 'Pos';
            app.S2DSecondPosEditField = uieditfield(app.S2DGridPanel, 'numeric');
            app.S2DSecondPosEditField.Value = 88;
            app.S2DRunButton = uibutton(app.Scan2DTab, 'push');
            app.S2DRunButton.ButtonPushedFcn = createCallbackFcn(app, @S2DRunButtonPushed, true);
            app.S2DRunButton.Text = 'Run';
            app.S2DRepeatsPanel = uipanel(app.Scan2DTab);
            app.S2DRepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel_2 = uilabel(app.S2DRepeatsPanel);
            app.RepeatsEditFieldLabel_2.Text = 'Repeats';
            app.S2DRepeatsEditField = uieditfield(app.S2DRepeatsPanel, 'numeric');
            app.S2DRepeatsEditField.Value = 1;
            app.Scan3DTab = uitab(app.MeasMethod);
            app.Scan3DTab.Title = 'Scan 3D';
            app.s3DGridPanel = uipanel(app.Scan3DTab);
            app.s3DGridPanel.Title = 'Stages';
            app.mmLabel_4 = uilabel(app.s3DGridPanel);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.s3DGridPanel);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel_6 = uilabel(app.s3DGridPanel);
            app.mmLabel_6.Text = '[mm]';
            app.mmLabel_7 = uilabel(app.s3DGridPanel);
            app.mmLabel_7.Text = '[mm]';
            app.mmLabel_8 = uilabel(app.s3DGridPanel);
            app.mmLabel_8.Text = '[mm]';
            app.mmLabel_9 = uilabel(app.s3DGridPanel);
            app.mmLabel_9.Text = '[mm]';
            app.StartEditFieldLabel = uilabel(app.s3DGridPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.S3DFirstStartEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstStartEditField.Value = 80;
            app.EndEditFieldLabel = uilabel(app.s3DGridPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.S3DFirstEndEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstEndEditField.Value = 90;
            app.StartEditField_2Label = uilabel(app.s3DGridPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.S3DSecondStartEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondStartEditField.Value = 100;
            app.EndEditField_2Label = uilabel(app.s3DGridPanel);
            app.EndEditField_2Label.Text = 'End';
            app.S3DSecondEndEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondEndEditField.Value = 99;
            app.StrideEditFieldLabel = uilabel(app.s3DGridPanel);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.S3DFirstStrideEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DFirstStrideEditField.Value = 1;
            app.StrideEditField_2Label = uilabel(app.s3DGridPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.S3DSecondStrideEditField = uieditfield(app.s3DGridPanel, 'numeric');
            app.S3DSecondStrideEditField.Value = -1;
            app.AxisDropDownLabel = uilabel(app.s3DGridPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.S3DFirstAxisDropDown = uidropdown(app.s3DGridPanel);
            app.S3DFirstAxisDropDown.Items = {'Y', 'X'};
            app.S3DFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DFirstAxisDropDownValueChanged, true);
            app.S3DFirstAxisDropDown.Value = 'Y';
            app.S3DImportLimitsfromfileButton = uibutton(app.s3DGridPanel, 'push');
            app.S3DImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @S3DImportLimitsfromfileButtonPushed, true);
            app.S3DImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.S3DFirstScanAxisLabel = uilabel(app.s3DGridPanel);
            app.S3DFirstScanAxisLabel.Text = 'First Scan Axis:';
            app.S3DSecondScanAxisLabel = uilabel(app.s3DGridPanel);
            app.S3DSecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label = uilabel(app.s3DGridPanel);
            app.AxisDropDown_2Label.Text = 'Axis';
            app.S3DSecondAxisDropDown = uidropdown(app.s3DGridPanel);
            app.S3DSecondAxisDropDown.Items = {'X', 'Z'};
            app.S3DSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DSecondAxisDropDownValueChanged, true);
            app.S3DSecondAxisDropDown.Value = 'X';
            app.S3DRunButton = uibutton(app.Scan3DTab, 'push');
            app.S3DRunButton.ButtonPushedFcn = createCallbackFcn(app, @S3DRunButtonPushed, true);
            app.S3DRunButton.Text = 'Run';
            app.S3DRepeatsPanel = uipanel(app.Scan3DTab);
            app.S3DRepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel = uilabel(app.S3DRepeatsPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.S3DRepeatsEditField = uieditfield(app.S3DRepeatsPanel, 'numeric');
            app.S3DRepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @S3DRepeatsEditFieldValueChanged, true);
            app.S3DRepeatsEditField.Value = 1;
            app.S3DImportResultsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportS2DscanResultsButtonPushed, true);
            app.S3DImportResultsButton.Text = 'Import Results';
            app.S3DExportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DExportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S3DExportScanVarsButtonPushed, true);
            app.S3DExportScanVarsButton.Text = 'Export Scan Vars';
            app.S3DImportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S3DImportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S3DImportScanVarsButtonPushed, true);
            app.S3DImportScanVarsButton.Text = 'Import Scan Vars';
            app.DeepViewTab = uitab(app.MeasMethod);
            app.DeepViewTab.Title = 'Deep View';
            app.DVGridPanel = uipanel(app.DeepViewTab);
            app.DVGridPanel.Title = 'Stages';
            app.DVFirstScanAxisLabel = uilabel(app.DVGridPanel);
            app.DVFirstScanAxisLabel.Text = 'First Scan Axis:';
            app.AxisDropDownLabel_3 = uilabel(app.DVGridPanel);
            app.AxisDropDownLabel_3.Text = 'Axis';
            app.DVFirstAxisDropDown = uidropdown(app.DVGridPanel);
            app.DVFirstAxisDropDown.Items = {'Y', 'X'};
            app.DVFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DVFirstAxisDropDownValueChanged, true);
            app.DVFirstAxisDropDown.Value = 'Y';
            app.mmLabel_25 = uilabel(app.DVGridPanel);
            app.mmLabel_25.Text = '[mm]';
            app.StartEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.StartEditFieldLabel_3.Text = 'Start';
            app.DVFirstStartEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstStartEditField.Value = 5;
            app.mmLabel_26 = uilabel(app.DVGridPanel);
            app.mmLabel_26.Text = '[mm]';
            app.StrideEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.StrideEditFieldLabel_3.Text = 'Stride';
            app.DVFirstStrideEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstStrideEditField.Value = 1;
            app.mmLabel_27 = uilabel(app.DVGridPanel);
            app.mmLabel_27.Text = '[mm]';
            app.EndEditFieldLabel_3 = uilabel(app.DVGridPanel);
            app.EndEditFieldLabel_3.Text = 'End';
            app.DVFirstEndEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVFirstEndEditField.Value = 20;
            app.DVSecondScanAxisLabel = uilabel(app.DVGridPanel);
            app.DVSecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label_3 = uilabel(app.DVGridPanel);
            app.AxisDropDown_2Label_3.Text = 'Axis';
            app.DVSecondAxisDropDown = uidropdown(app.DVGridPanel);
            app.DVSecondAxisDropDown.Items = {'X', 'Z'};
            app.DVSecondAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DVSecondAxisDropDownValueChanged, true);
            app.DVSecondAxisDropDown.Value = 'X';
            app.mmLabel_28 = uilabel(app.DVGridPanel);
            app.mmLabel_28.Text = '[mm]';
            app.PosLabel_2 = uilabel(app.DVGridPanel);
            app.PosLabel_2.Text = 'Pos';
            app.DVSecondPosEditField = uieditfield(app.DVGridPanel, 'numeric');
            app.DVSecondPosEditField.Value = 5;
            app.DVFiguresPanel = uipanel(app.DeepViewTab);
            app.DVFiguresPanel.Title = 'Figures';
            app.mmLabel_29 = uilabel(app.DVFiguresPanel);
            app.mmLabel_29.Text = '00.00[mm]';
            app.DepthCoorMainAxisLabel_4 = uilabel(app.DVFiguresPanel);
            app.DepthCoorMainAxisLabel_4.Text = {'Depth Coor'; '(Main Axis)'};
            app.DVCurDepthCoorSpinner = uispinner(app.DVFiguresPanel);
            app.DVCurDepthCoorSpinner.Limits = [0 1];
            app.DVCurDepthCoorSpinner.Enable = 'off';
            app.DVCurDepthCoorSpinner.Value = 1;
            app.TimePanel = uipanel(app.DeepViewTab);
            app.TimePanel.Title = 'Time';
            app.sLabel_7 = uilabel(app.TimePanel);
            app.sLabel_7.Text = '[s]';
            app.StartEditFieldLabel_4 = uilabel(app.TimePanel);
            app.StartEditFieldLabel_4.Text = 'Start';
            app.DVTimeStartEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeStartEditField.Value = 5;
            app.sLabel_8 = uilabel(app.TimePanel);
            app.sLabel_8.Text = '[s]';
            app.StrideEditFieldLabel_4 = uilabel(app.TimePanel);
            app.StrideEditFieldLabel_4.Text = 'Stride';
            app.DVTimeStrideEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeStrideEditField.Value = 1;
            app.sLabel_9 = uilabel(app.TimePanel);
            app.sLabel_9.Text = '[s]';
            app.EndEditFieldLabel_4 = uilabel(app.TimePanel);
            app.EndEditFieldLabel_4.Text = 'End';
            app.DVTimeEndEditField = uieditfield(app.TimePanel, 'numeric');
            app.DVTimeEndEditField.Value = 20;
            app.DVRunButton = uibutton(app.DeepViewTab, 'push');
            app.DVRunButton.Text = 'Run';
            app.ConsistencyTab = uitab(app.MeasMethod);
            app.ConsistencyTab.Title = 'Consistency';
            app.RunButtonConsistency = uibutton(app.ConsistencyTab, 'push');
            app.RunButtonConsistency.ButtonPushedFcn = createCallbackFcn(app, @RunButtonConsistencyPushed, true);
            app.RunButtonConsistency.Text = 'Run';
            app.StagesPanel_2 = uipanel(app.ConsistencyTab);
            app.StagesPanel_2.Title = 'Stages';
            app.mmLabel_10 = uilabel(app.StagesPanel_2);
            app.mmLabel_10.Text = '[mm]';
            app.mmLabel_11 = uilabel(app.StagesPanel_2);
            app.mmLabel_11.Text = '[mm]';
            app.XpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.XpositionEditField.Value = 124;
            app.YpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.YpositionEditField.Value = 90;
            app.TimeFramePropertiesPanel = uipanel(app.ConsistencyTab);
            app.TimeFramePropertiesPanel.Title = 'Time Frame Properties';
            app.sLabel = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_2.Text = '[s]';
            app.sLabel_3 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_4.Text = '[s]';
            app.MaxTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MaxTimeFrameDurationEditFieldLabel.Text = 'Max Time Frame Duration';
            app.MaxTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MaxTimeFrameDurationEditField.Value = 8;
            app.MinTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MinTimeFrameDurationEditFieldLabel.Text = 'Min Time Frame Duration';
            app.MinTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MinTimeFrameDurationEditField.Value = 2;
            app.TimeStrideEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.TimeStrideEditField.Value = 2;
            app.SetsperTimeFrameEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.SetsperTimeFrameEditFieldLabel.Text = 'Sets per Time Frame';
            app.SetsperTimeFrameEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.SetsperTimeFrameEditField.Value = 20;
            app.ImportResultsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportResultsButton_2.Enable = 'off';
            app.ImportResultsButton_2.Text = 'Import Results';
            app.ExportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ExportScanVarsButton_2.Text = 'Export Scan Vars';
            app.ImportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportScanVarsButton_2.Text = 'Import Scan Vars';
            app.FiguresPanel_2 = uipanel(app.ConsistencyTab);
            app.FiguresPanel_2.Title = 'Figures';
            app.FrameToPlotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.FrameToPlotSpinnerLabel.Text = 'Frame To Plot';
            app.FrameToPlotSpinner = uispinner(app.FiguresPanel_2);
            app.FrameToPlotSpinner.Limits = [1 2];
            app.FrameToPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameToPlotSpinnerValueChanged, true);
            app.FrameToPlotSpinner.Value = 1;
            app.SettoplotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.SettoplotSpinnerLabel.Text = 'Set to plot';
            app.SettoplotSpinner = uispinner(app.FiguresPanel_2);
            app.SettoplotSpinner.Limits = [1 2];
            app.SettoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @SettoplotSpinnerValueChanged, true);
            app.SettoplotSpinner.Value = 1;
            app.ZCoorSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.ZCoorSpinnerLabel.Text = 'Z Coor';
            app.ZCoorConsSpinner = uispinner(app.FiguresPanel_2);
            app.ZCoorConsSpinner.ValueChangingFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanging, true);
            app.ZCoorConsSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanged, true);
            app.ZCoorConsSpinner.Value = 1;
            app.sLabel_6 = uilabel(app.FiguresPanel_2);
            app.sLabel_6.Text = '0 [s]';
            app.mmLabel_15 = uilabel(app.FiguresPanel_2);
            app.mmLabel_15.Text = '00.00[mm]';
            app.GlobalParameters = uitabgroup(app.UIFigure);
            app.GlobalParameters.SelectionChangedFcn = createCallbackFcn(app, @GlobalParametersSelectionChanged, true);
            app.SignalExcitationTab = uitab(app.GlobalParameters);
            app.SignalExcitationTab.Title = 'Signal Excitation';
            app.UseQuantCheckBox = uicheckbox(app.SignalExcitationTab);
            app.UseQuantCheckBox.Text = 'Use Quant';
            app.SamplingFrequencyextClkPanel = uipanel(app.SignalExcitationTab);
            app.SamplingFrequencyextClkPanel.Title = 'Sampling Frequency (extClk)';
            app.MHzLabel_2 = uilabel(app.SamplingFrequencyextClkPanel);
            app.MHzLabel_2.Text = '[MHz]';
            app.VLabel_10 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_10.Text = '[V]';
            app.VLabel_11 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_11.Text = '[V]';
            app.Label_12 = uilabel(app.SamplingFrequencyextClkPanel);
            app.Label_12.Text = '[%]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 5;
            app.AmplitudeEditField_4Label = uilabel(app.SamplingFrequencyextClkPanel);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.AmplitudeEditField_4.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.OffsetEditField.Value = '0';
            app.DutyCycleEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.UltrasoundSignalPropertiesPanel = uipanel(app.SignalExcitationTab);
            app.UltrasoundSignalPropertiesPanel.Title = 'Ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_13 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_13.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SineFrequencyEditFieldLabel.Text = 'Sine Frequency';
            app.SineFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SineFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SineFrequencyEditFieldValueChanged, true);
            app.SineFrequencyEditField.Value = 1.25;
            app.TrainFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.TrainFrequencyEditFieldLabel.Text = 'Train Frequency';
            app.TrainFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.TrainFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @TrainFrequencyEditFieldValueChanged, true);
            app.TrainFrequencyEditField.Value = 15.9;
            app.SinePeriodsEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SinePeriodsEditFieldLabel.Text = 'Sine Periods';
            app.SinePeriodsEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SinePeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @SinePeriodsEditFieldValueChanged, true);
            app.SinePeriodsEditField.Value = 1;
            app.OptimizetrainfrequencyCheckBox = uicheckbox(app.UltrasoundSignalPropertiesPanel);
            app.OptimizetrainfrequencyCheckBox.Text = 'Optimize train frequency';
            app.GeometryPanel = uipanel(app.SignalExcitationTab);
            app.GeometryPanel.Title = 'Geometry';
            app.PhantomdepthEditFieldLabel = uilabel(app.GeometryPanel);
            app.PhantomdepthEditFieldLabel.Text = 'Phantom depth';
            app.PhantomdepthEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.PhantomdepthEditField.Value = 4.6;
            app.TransducerheightEditFieldLabel = uilabel(app.GeometryPanel);
            app.TransducerheightEditFieldLabel.Text = 'Transducer height';
            app.TransducerheightEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerheightEditField.Value = 11.2;
            app.TransducerdistanceLabel = uilabel(app.GeometryPanel);
            app.TransducerdistanceLabel.Text = 'Transducer distance';
            app.TransducerdistanceEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerdistanceEditField.Value = 7.2;
            app.msLabel = uilabel(app.GeometryPanel);
            app.msLabel.Text = '[m/s]';
            app.SpeedinMediumEditFieldLabel = uilabel(app.GeometryPanel);
            app.SpeedinMediumEditFieldLabel.Text = 'Speed in Medium';
            app.SpeedinMediumEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SpeedinMediumEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedinMediumEditFieldValueChanged, true);
            app.SpeedinMediumEditField.Value = 1500;
            app.cmLabel_3 = uilabel(app.GeometryPanel);
            app.cmLabel_3.Text = '[cm]';
            app.cmLabel_2 = uilabel(app.GeometryPanel);
            app.cmLabel_2.Text = '[cm]';
            app.cmLabel = uilabel(app.GeometryPanel);
            app.cmLabel.Text = '[cm]';
            app.GeneralPanel = uipanel(app.SignalExcitationTab);
            app.GeneralPanel.Title = 'General';
            app.TimetosampleEditFieldLabel = uilabel(app.GeneralPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TimetosampleEditField.Value = 2;
            app.UseTimeQuantCheckBox = uicheckbox(app.GeneralPanel);
            app.UseTimeQuantCheckBox.Text = 'Use Time Quant';
            app.UseTimeQuantCheckBox.Value = true;
            app.QuantTimeEditFieldLabel = uilabel(app.GeneralPanel);
            app.QuantTimeEditFieldLabel.Text = 'Quant Time';
            app.QuantTimeEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.QuantTimeEditField.Value = 0.002;
            app.FastAnalysisCheckBox = uicheckbox(app.GeneralPanel);
            app.FastAnalysisCheckBox.Enable = 'off';
            app.FastAnalysisCheckBox.Text = 'Fast Analysis';
            app.UseGPUCheckBox = uicheckbox(app.GeneralPanel);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.CalcOnlyUSFrequencyLabel = uilabel(app.GeneralPanel);
            app.CalcOnlyUSFrequencyLabel.Enable = 'off';
            app.CalcOnlyUSFrequencyLabel.Text = '(Calc Only US Frequency)';
            app.secLabel = uilabel(app.GeneralPanel);
            app.secLabel.Text = '[sec]';
            app.secLabel_2 = uilabel(app.GeneralPanel);
            app.secLabel_2.Text = '[sec]';
            app.UseHadamardCodeCheckBox = uicheckbox(app.GeneralPanel);
            app.UseHadamardCodeCheckBox.Text = 'Use Hadamard Code';
            app.UseHadamardCodeCheckBox.Value = true;
            app.SinglemeasLimitEditFieldLabel = uilabel(app.GeneralPanel);
            app.SinglemeasLimitEditFieldLabel.Text = 'Single meas. Limit';
            app.SinglemeasLimitEditField = uieditfield(app.GeneralPanel, 'text');
            app.SinglemeasLimitEditField.ValueChangedFcn = createCallbackFcn(app, @SinglemeasLimitEditFieldValueChanged, true);
            app.SinglemeasLimitEditField.Value = '10';
            app.secLabel_3 = uilabel(app.GeneralPanel);
            app.secLabel_3.Text = '[sec]';
            app.FittingEnviromentsPanel = uipanel(app.SignalExcitationTab);
            app.FittingEnviromentsPanel.Title = 'Fitting Enviroments';
            app.DCEditFieldLabel = uilabel(app.FittingEnviromentsPanel);
            app.DCEditFieldLabel.Text = 'DC';
            app.envDC = uieditfield(app.FittingEnviromentsPanel, 'numeric');
            app.envDC.Value = 100;
            app.USEditFieldLabel = uilabel(app.FittingEnviromentsPanel);
            app.USEditFieldLabel.Text = 'US';
            app.envUS = uieditfield(app.FittingEnviromentsPanel, 'numeric');
            app.envUS.Value = 78;
            app.KHzLabel_6 = uilabel(app.FittingEnviromentsPanel);
            app.KHzLabel_6.Text = '[KHz]';
            app.KHzLabel_7 = uilabel(app.FittingEnviromentsPanel);
            app.KHzLabel_7.Text = '[KHz]';
            app.KHzLabel_8 = uilabel(app.FittingEnviromentsPanel);
            app.KHzLabel_8.Text = '[KHz]';
            app.ndHarLabel = uilabel(app.FittingEnviromentsPanel);
            app.ndHarLabel.Text = '2nd Har.';
            app.envHar = uieditfield(app.FittingEnviromentsPanel, 'numeric');
            app.envHar.Value = 200;
            app.PeripheralsTab = uitab(app.GlobalParameters);
            app.PeripheralsTab.Title = 'Peripherals';
            app.FunctionGeneratorPanel = uipanel(app.PeripheralsTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SclkEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SclkEditFieldLabel.Text = 'Sclk';
            app.SclkEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SclkEditField.Value = 100;
            app.DAQFilterDropDownLabel = uilabel(app.FunctionGeneratorPanel);
            app.DAQFilterDropDownLabel.Text = 'DAQ Filter';
            app.DAQFilterDropDown = uidropdown(app.FunctionGeneratorPanel);
            app.DAQFilterDropDown.Items = {'25MHz', '50MHz'};
            app.DAQFilterDropDown.Value = '50MHz';
            app.TriggerWidthEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.TriggerWidthEditFieldLabel.Text = 'Trigger Width';
            app.TriggerWidthEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.TriggerWidthEditField.Value = 500;
            app.AmplitudeEditField_3Label = uilabel(app.FunctionGeneratorPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.AmplitudeEditField_3.Value = 1;
            app.BiasEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.MSsLabel = uilabel(app.FunctionGeneratorPanel);
            app.MSsLabel.Text = '[MS/s]';
            app.SLabel = uilabel(app.FunctionGeneratorPanel);
            app.SLabel.Text = '[S]';
            app.VLabel_8 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_8.Text = '[V]';
            app.VLabel_9 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_9.Text = '[V]';
            app.DigitizerPanel = uipanel(app.PeripheralsTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.Label_10 = uilabel(app.DigitizerPanel);
            app.Label_10.Text = '[#]';
            app.NumberOfChannelsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.NumberOfChannelsEditFieldLabel.Text = 'Number Of Channels';
            app.NumberOfChannelsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.NumberOfChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfChannelsEditFieldValueChanged, true);
            app.NumberOfChannelsEditField.Value = 4;
            app.Label_11 = uilabel(app.DigitizerPanel);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = '[#]';
            app.PeriodtoSampleEditFieldLabel = uilabel(app.DigitizerPanel);
            app.PeriodtoSampleEditFieldLabel.Text = 'Period to Sample';
            app.PeriodtoSampleEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.PeriodtoSampleEditField.Enable = 'off';
            app.PeriodtoSampleEditField.Value = 8192;
            app.VppLabel = uilabel(app.DigitizerPanel);
            app.VppLabel.Text = '[Vpp]';
            app.VoltsRangeEditFieldLabel = uilabel(app.DigitizerPanel);
            app.VoltsRangeEditFieldLabel.Text = 'Volts Range';
            app.VoltsRangeEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.VoltsRangeEditField.Value = 2;
            app.SamplingMethodDropDownLabel = uilabel(app.DigitizerPanel);
            app.SamplingMethodDropDownLabel.Text = 'Sampling Method';
            app.SamplingMethodDropDown = uidropdown(app.DigitizerPanel);
            app.SamplingMethodDropDown.Items = {'NPT', 'TS'};
            app.SamplingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingMethodDropDownValueChanged, true);
            app.SamplingMethodDropDown.Value = 'TS';
            app.LaserPanel = uipanel(app.PeripheralsTab);
            app.LaserPanel.Title = 'Laser';
            app.nmLabel = uilabel(app.LaserPanel);
            app.nmLabel.Text = '[nm]';
            app.mWLabel = uilabel(app.LaserPanel);
            app.mWLabel.Text = '[mW]';
            app.KHzLabel_4 = uilabel(app.LaserPanel);
            app.KHzLabel_4.Text = '[KHz]';
            app.sLabel_5 = uilabel(app.LaserPanel);
            app.sLabel_5.Text = '[s]';
            app.WavelengthEditFieldLabel = uilabel(app.LaserPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.WavelengthEditField.Value = 785;
            app.PowerEditFieldLabel = uilabel(app.LaserPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserPanel, 'numeric');
            app.PowerEditField.Value = 50;
            app.LineWidthEditFieldLabel = uilabel(app.LaserPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.LineWidthEditField.Value = 5000;
            app.SpeckleDurationEditFieldLabel = uilabel(app.LaserPanel);
            app.SpeckleDurationEditFieldLabel.Text = 'Speckle Duration';
            app.SpeckleDurationEditField = uieditfield(app.LaserPanel, 'numeric');
            app.SpeckleDurationEditField.Value = 0.5;
            app.TurnOffAFGButton = uibutton(app.PeripheralsTab, 'push');
            app.TurnOffAFGButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAFGButtonPushed, true);
            app.TurnOffAFGButton.Text = 'Turn Off AFG';
            app.CloseIOPortButton = uibutton(app.PeripheralsTab, 'push');
            app.CloseIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIOPortButtonPushed, true);
            app.CloseIOPortButton.Text = 'Close IO Port';
            app.AOVarsTab = uitab(app.GlobalParameters);
            app.AOVarsTab.Title = 'AO Vars';
            app.TabGroup2 = uitabgroup(app.AOVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable_6 = uitable(app.SystemClkTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_5 = uitable(app.USSignalTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable = uitable(app.SamplesTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_3 = uitable(app.TimingTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.DigitizerTab_2 = uitab(app.TabGroup2);
            app.DigitizerTab_2.Title = 'Digitizer';
            app.UITable_4 = uitable(app.DigitizerTab_2);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_2 = uitable(app.LengthTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.PlottingTab = uitab(app.GlobalParameters);
            app.PlottingTab.Title = 'Plotting';
            app.AcoustoOpticsPanel = uipanel(app.PlottingTab);
            app.AcoustoOpticsPanel.Title = 'Acousto Optics';
            app.PlotChannelSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.PlotChannelSpinnerLabel.Text = 'Plot Channel';
            app.PlotChannelSpinner = uispinner(app.AcoustoOpticsPanel);
            app.PlotChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelAOSpinnerValueChanged, true);
            app.PlotChannelSpinner.Value = 1;
            app.DepthCoorLabel = uilabel(app.AcoustoOpticsPanel);
            app.DepthCoorLabel.Text = 'Depth Coor';
            app.AOZIdxSpinner = uispinner(app.AcoustoOpticsPanel);
            app.AOZIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @AOZIdxSpinnerValueChanged, true);
            app.AOZIdxSpinner.Value = 1;
            app.QuantSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.QuantSpinnerLabel.Text = 'Quant';
            app.QuantAOSpinner = uispinner(app.AcoustoOpticsPanel);
            app.QuantAOSpinner.ValueChangedFcn = createCallbackFcn(app, @QuantAOSpinnerValueChanged, true);
            app.QuantAOSpinner.Value = 1;
            app.ExternalClkCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.ExternalClkCheckBox.Text = 'External Clk';
            app.UltrasoundSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.UltrasoundSignalCheckBox.Text = 'Ultrasound Signal';
            app.FullSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FullSignalCheckBox.Text = 'Full Signal';
            app.MeasuredSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.MeasuredSamplesCheckBox.Text = 'Measured Samples';
            app.NetSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.NetSamplesCheckBox.Text = 'Net Samples';
            app.ReshapedCheckBox_3 = uicheckbox(app.AcoustoOpticsPanel);
            app.ReshapedCheckBox_3.Text = 'Reshaped';
            app.UnfittedDataCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.UnfittedDataCheckBox.Text = 'Unfitted Data';
            app.UnfittedDataCheckBox.Value = true;
            app.PhiCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiCheckBox.Text = 'Phi';
            app.PhiCheckBox.Value = true;
            app.FittedDataCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FittedDataCheckBox.Text = 'Fitted Data';
            app.FittedDataCheckBox.Value = true;
            app.DemultiplexedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.DemultiplexedCheckBox.Text = 'Demultiplexed';
            app.FreqEnviromentEditFieldLabel = uilabel(app.AcoustoOpticsPanel);
            app.FreqEnviromentEditFieldLabel.Enable = 'off';
            app.FreqEnviromentEditFieldLabel.Text = 'Freq Enviroment';
            app.FreqEnviromentEditField = uieditfield(app.AcoustoOpticsPanel, 'numeric');
            app.FreqEnviromentEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEnviromentAOEditFieldValueChanged, true);
            app.FreqEnviromentEditField.Enable = 'off';
            app.FreqEnviromentEditField.Value = 250;
            app.KHzLabel_5 = uilabel(app.AcoustoOpticsPanel);
            app.KHzLabel_5.Enable = 'off';
            app.KHzLabel_5.Text = 'KHz';
            app.mmLabel_16 = uilabel(app.AcoustoOpticsPanel);
            app.mmLabel_16.Text = '00.00[mm]';
            app.dispFullFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.dispFullFFTCheckBox.Text = 'disp Full FFT';
            app.dispFullFFTCheckBox.Value = true;
            app.QuantAvgFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.QuantAvgFFTCheckBox.Text = 'Quant Avg. FFT';
            app.QuantAvgFFTCheckBox.Value = true;
            app.DepthAxisasInexCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.DepthAxisasInexCheckBox.ValueChangedFcn = createCallbackFcn(app, @DepthAxisasInexCheckBoxValueChanged, true);
            app.DepthAxisasInexCheckBox.Text = 'Depth Axis as Inex';
            app.StopButton = uibutton(app.PlottingTab, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop!';
            app.PopoutFiguresPanel = uipanel(app.PlottingTab);
            app.PopoutFiguresPanel.Title = 'Pop out Figures';
            app.PopButton = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopButton.ButtonPushedFcn = createCallbackFcn(app, @PopButtonPushed, true);
            app.PopButton.Text = 'Pop!';
            app.FiguresToPopDropDown = uidropdown(app.PopoutFiguresPanel);
            app.FiguresToPopDropDown.Items = {'Acousto Optics', 'Scan 2D', 'Scan 3D', 'Deep View'};
            app.FiguresToPopDropDown.Value = 'Acousto Optics';
            app.ExternalFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ExternalFiguresCheckBox.Text = 'External Figures';
            app.ReOpenFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ReOpenFiguresCheckBox.Text = 'Re-Open Figures';
            app.TimeTablePanel = uipanel(app.PlottingTab);
            app.TimeTablePanel.Title = 'Time Table';
            app.DisplayTimeTableCheckBox = uicheckbox(app.TimeTablePanel);
            app.DisplayTimeTableCheckBox.Text = 'Display Time Table';
            app.DisplayTimeTableCheckBox.Value = true;
            app.Scan3DFiguresPanel = uipanel(app.PlottingTab);
            app.Scan3DFiguresPanel.Title = 'Scan 3D Figures';
            app.NavPlainDropDownLabel = uilabel(app.Scan3DFiguresPanel);
            app.NavPlainDropDownLabel.Text = 'Nav Plain';
            app.S3DNavPlainDropDown = uidropdown(app.Scan3DFiguresPanel);
            app.S3DNavPlainDropDown.Items = {'X', 'Y', 'Z'};
            app.S3DNavPlainDropDown.ValueChangedFcn = createCallbackFcn(app, @S3DNavPlainDropDownValueChanged, true);
            app.S3DNavPlainDropDown.Enable = 'off';
            app.S3DNavPlainDropDown.Value = 'Z';
            app.ReapeatSpinnerLabel = uilabel(app.Scan3DFiguresPanel);
            app.ReapeatSpinnerLabel.Text = 'Reapeat';
            app.S3DRepeatSpinner = uispinner(app.Scan3DFiguresPanel);
            app.S3DRepeatSpinner.Limits = [1 Inf];
            app.S3DRepeatSpinner.ValueChangedFcn = createCallbackFcn(app, @S3DRepeatSpinnerValueChanged, true);
            app.S3DRepeatSpinner.Enable = 'off';
            app.S3DRepeatSpinner.Value = 1;
            app.NavIdxSpinnerLabel = uilabel(app.Scan3DFiguresPanel);
            app.NavIdxSpinnerLabel.Text = 'Nav Idx';
            app.S3DNavIdxSpinner = uispinner(app.Scan3DFiguresPanel);
            app.S3DNavIdxSpinner.LowerLimitInclusive = 'off';
            app.S3DNavIdxSpinner.Limits = [0 Inf];
            app.S3DNavIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @S3DNavIdxSpinnerValueChanged, true);
            app.S3DNavIdxSpinner.Enable = 'off';
            app.S3DNavIdxSpinner.Value = 1;
            app.mmLabel_14 = uilabel(app.Scan3DFiguresPanel);
            app.mmLabel_14.Text = '00.00[mm]';
            app.NormalizeColorstoDisplayedPlaneCheckBox = uicheckbox(app.Scan3DFiguresPanel);
            app.NormalizeColorstoDisplayedPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeColorstoDisplayedPlaneCheckBoxValueChanged, true);
            app.NormalizeColorstoDisplayedPlaneCheckBox.Text = 'Normalize Colors to Displayed Plane';
            app.AxespropertiesPanel = uipanel(app.PlottingTab);
            app.AxespropertiesPanel.Title = 'Axes properties';
            app.XDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.AxespropertiesPanel);
            app.XDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.XDropDown.Value = 'Normal';
            app.YDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.AxespropertiesPanel);
            app.YDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.YDropDown.Value = 'Normal';
            app.ZDropDownLabel = uilabel(app.AxespropertiesPanel);
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.AxespropertiesPanel);
            app.ZDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.ZDropDown.Value = 'Normal';
            app.S2DFiguresPanel = uipanel(app.PlottingTab);
            app.S2DFiguresPanel.Title = 'Scan 2D Figures';
            app.mmLabel_24 = uilabel(app.S2DFiguresPanel);
            app.mmLabel_24.Text = '00.00[mm]';
            app.DepthCoorLabel_2 = uilabel(app.S2DFiguresPanel);
            app.DepthCoorLabel_2.Text = {'Depth Coor'; ''};
            app.S2DCurDepthCoorSpinner = uispinner(app.S2DFiguresPanel);
            app.S2DCurDepthCoorSpinner.Limits = [0 1];
            app.S2DCurDepthCoorSpinner.Enable = 'off';
            app.S2DCurDepthCoorSpinner.Value = 1;
            app.StagesTab = uitab(app.GlobalParameters);
            app.StagesTab.Title = 'Stages';
            app.StagesLayoutInSystemPanel = uipanel(app.StagesTab);
            app.StagesLayoutInSystemPanel.Title = 'Stages Layout In System';
            app.DepthAxisDropDownLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.DepthAxisDropDownLabel.Text = 'Depth Axis';
            app.DepthAxisDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.DepthAxisDropDown.Items = {'Z', 'X', 'Y'};
            app.DepthAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DepthAxisDropDownValueChanged, true);
            app.DepthAxisDropDown.Value = 'Z';
            app.stage1Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stage1Label.Text = 'X';
            app.stage2Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stage2Label.Text = 'Y';
            app.stageIDDropDownLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.stageIDDropDownLabel.Text = 'stage ID';
            app.stage1IDDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.stage1IDDropDown.Items = {'1', '2'};
            app.stage1IDDropDown.ValueChangedFcn = createCallbackFcn(app, @stage1IDDropDownValueChanged, true);
            app.stage1IDDropDown.Value = '1';
            app.stageIDDropDown_2Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stageIDDropDown_2Label.Text = 'stage ID';
            app.stage2IDDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.stage2IDDropDown.Items = {'1', '2'};
            app.stage2IDDropDown.ValueChangedFcn = createCallbackFcn(app, @stage2IDDropDownValueChanged, true);
            app.stage2IDDropDown.Value = '2';
            app.SetStagesGeometryButton = uibutton(app.StagesLayoutInSystemPanel, 'push');
            app.SetStagesGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @SetStagesGeometryButtonPushed, true);
            app.SetStagesGeometryButton.Text = {'Set Stages '; 'Geometry'};
            app.TestAssignmentButton = uibutton(app.StagesLayoutInSystemPanel, 'push');
            app.TestAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @TestAssignmentButtonPushed, true);
            app.TestAssignmentButton.Text = 'Test Assignment';
            app.TestLableLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.TestLableLabel.Visible = 'off';
            app.TestLableLabel.Text = 'Test Lable';
            app.Stage1Panel = uipanel(app.StagesTab);
            app.Stage1Panel.Title = 'Stage 1';
            app.PositionEditFieldLabel = uilabel(app.Stage1Panel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.Stage1PositionEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.mmLabel_30 = uilabel(app.Stage1Panel);
            app.mmLabel_30.Text = '[mm]';
            app.Stage1MoveToPositionButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1MoveToPositionButtonPushed, true);
            app.Stage1MoveToPositionButton.Text = 'Move To Position';
            app.Stage1Increase = uibutton(app.Stage1Panel, 'push');
            app.Stage1Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage1IncreaseButtonPushed, true);
            app.Stage1Increase.Text = '+';
            app.Stage1Decrease = uibutton(app.Stage1Panel, 'push');
            app.Stage1Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage1DecreaseButtonPushed, true);
            app.Stage1Decrease.Text = '-';
            app.mmLabel_31 = uilabel(app.Stage1Panel);
            app.mmLabel_31.Text = '[mm]';
            app.Stage1SetStartButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStartButtonPushed, true);
            app.Stage1SetStartButton.Text = 'Set Start';
            app.Stage1SetEndButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetEndButtonPushed, true);
            app.Stage1SetEndButton.Text = 'Set End';
            app.Stage1SetStepButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStepButtonPushed, true);
            app.Stage1SetStepButton.Text = 'Set Step';
            app.StrideEditFieldLabel_5 = uilabel(app.Stage1Panel);
            app.StrideEditFieldLabel_5.Text = 'Stride';
            app.Stage1StrideEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.Stage2Panel = uipanel(app.StagesTab);
            app.Stage2Panel.Title = 'Stage 2';
            app.PositionEditField_2Label = uilabel(app.Stage2Panel);
            app.PositionEditField_2Label.Text = 'Position';
            app.Stage2PositionEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.mmLabel_32 = uilabel(app.Stage2Panel);
            app.mmLabel_32.Text = '[mm]';
            app.Stage2MoveToPositionButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2MoveToPositionButtonPushed, true);
            app.Stage2MoveToPositionButton.Text = 'Move To Position';
            app.mmLabel_33 = uilabel(app.Stage2Panel);
            app.mmLabel_33.Text = '[mm]';
            app.Stage2Increase = uibutton(app.Stage2Panel, 'push');
            app.Stage2Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage2IncreaseButtonPushed, true);
            app.Stage2Increase.Text = '+';
            app.Stage2Decrease = uibutton(app.Stage2Panel, 'push');
            app.Stage2Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage2DecreaseButtonPushed, true);
            app.Stage2Decrease.Text = '-';
            app.Stage2SetStartButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStartButtonPushed, true);
            app.Stage2SetStartButton.Text = 'Set Start';
            app.Stage2SetEndButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetEndButtonPushed, true);
            app.Stage2SetEndButton.Text = 'Set End';
            app.Stage2SetStepButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStepButtonPushed, true);
            app.Stage2SetStepButton.Text = 'Set Step';
            app.StrideEditField_2Label_2 = uilabel(app.Stage2Panel);
            app.StrideEditField_2Label_2.Text = 'Stride';
            app.Stage2StrideEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.stagesTable = uitable(app.StagesTab);
            app.stagesTable.ColumnName = {'Stage 1'; 'Stage 2'};
            app.stagesTable.RowName = {'Position'; 'Start'; 'Stride'; 'End'};
            app.Panel = uipanel(app.StagesTab);
            app.Panel.Title = 'Panel';
            app.ExportToFileButton = uibutton(app.Panel, 'push');

            app.ExportToFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToFileButtonPushed, true);
            app.ExportToFileButton.Text = 'Export To File';
            app.ExporttoscanDropDownLabel = uilabel(app.Panel);
            app.ExporttoscanDropDownLabel.Text = 'Export to scan:';
            app.ExporttoscanDropDown = uidropdown(app.Panel);
            app.ExporttoscanDropDown.Items = {'Scan 2D', 'Scan 3D', 'Deep View'};
            app.ExporttoscanDropDown.ValueChangedFcn = createCallbackFcn(app, @ExporttoscanDropDownValueChanged, true);
            app.ExporttoscanDropDown.Value = 'Scan 2D';
            app.CopytoScanButton = uibutton(app.Panel, 'push');
            app.CopytoScanButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoScanButtonPushed, true);
            app.CopytoScanButton.Text = 'Copy to Scan';
            app.AllStagesHomeButton = uibutton(app.StagesTab, 'push');
            app.AllStagesHomeButton.ButtonPushedFcn = createCallbackFcn(app, @AllStagesHomeButtonPushed, true);
            app.AllStagesHomeButton.Text = 'All Stages Home';
            app.RefreshPositionButton = uibutton(app.StagesTab, 'push');
            app.RefreshPositionButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPositionButtonPushed, true);
            app.RefreshPositionButton.Text = 'Refresh Position';
            app.acoustoOpticsPlotsTab = uitabgroup(app.UIFigure);
            app.TimeTable = uitab(app.acoustoOpticsPlotsTab);
            app.TimeTable.Title = 'Time Table';
            app.UITable2 = uitable(app.TimeTable);
            app.UITable2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.TimeTable);
            app.UITable2_2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_2.RowName = {};
            app.UITable2_3 = uitable(app.TimeTable);
            app.UITable2_3.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_3.RowName = {};
            app.PhiTab = uitab(app.acoustoOpticsPlotsTab);
            app.PhiTab.Title = 'Phi';
            app.axPhi = uiaxes(app.PhiTab);
            title(app.axPhi, 'Title')
            xlabel(app.axPhi, 'X')
            ylabel(app.axPhi, 'Y')
            app.FittedFFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.FittedFFTTab.Title = 'FFT';
            app.axFittedFFT = uiaxes(app.FittedFFTTab);
            title(app.axFittedFFT, 'Title')
            xlabel(app.axFittedFFT, 'X')
            ylabel(app.axFittedFFT, 'Y')
            app.UnfittedFFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.UnfittedFFTTab.Title = 'Unfitted FFT';
            app.axUnfittedFFT = uiaxes(app.UnfittedFFTTab);
            title(app.axUnfittedFFT, 'Title')
            xlabel(app.axUnfittedFFT, 'X')
            ylabel(app.axUnfittedFFT, 'Y')
            app.ChFFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.ChFFTTab.Title = 'Ch FFT';
            app.axQAvgChFFT = uiaxes(app.ChFFTTab);
            title(app.axQAvgChFFT, 'Title')
            xlabel(app.axQAvgChFFT, 'X')
            ylabel(app.axQAvgChFFT, 'Y')
            app.USTab = uitab(app.acoustoOpticsPlotsTab);
            app.USTab.Title = 'US';
            app.axUsSignal = uiaxes(app.USTab);
            title(app.axUsSignal, 'Title')
            xlabel(app.axUsSignal, 'X')
            ylabel(app.axUsSignal, 'Y')
            app.ReshapedTab = uitab(app.acoustoOpticsPlotsTab);
            app.ReshapedTab.Title = 'Reshaped';
            app.axReshape = uiaxes(app.ReshapedTab);
            title(app.axReshape, 'Title')
            xlabel(app.axReshape, 'X')
            ylabel(app.axReshape, 'Y')
            app.NetTab = uitab(app.acoustoOpticsPlotsTab);
            app.NetTab.Title = 'Net';
            app.axNetSignal = uiaxes(app.NetTab);
            title(app.axNetSignal, 'Title')
            xlabel(app.axNetSignal, 'X')
            ylabel(app.axNetSignal, 'Y')
            app.DeMulTab = uitab(app.acoustoOpticsPlotsTab);
            app.DeMulTab.Title = 'DeMul';
            app.axDeMul = uiaxes(app.DeMulTab);
            title(app.axDeMul, 'Title')
            xlabel(app.axDeMul, 'X')
            ylabel(app.axDeMul, 'Y')
            app.MeasSigTab = uitab(app.acoustoOpticsPlotsTab);
            app.MeasSigTab.Title = 'Meas. Sig.';
            app.axMeasSig = uiaxes(app.MeasSigTab);
            title(app.axMeasSig, 'Title')
            xlabel(app.axMeasSig, 'X')
            ylabel(app.axMeasSig, 'Y')
            app.FullSigTab = uitab(app.acoustoOpticsPlotsTab);
            app.FullSigTab.Title = 'Full Sig.';
            app.axFullSignal = uiaxes(app.FullSigTab);
            title(app.axFullSignal, 'Title')
            xlabel(app.axFullSignal, 'X')
            ylabel(app.axFullSignal, 'Y')
            app.ExtClkTab = uitab(app.acoustoOpticsPlotsTab);
            app.ExtClkTab.Title = 'Ext. Clk';
            app.axExtClk = uiaxes(app.ExtClkTab);
            title(app.axExtClk, 'Title')
            xlabel(app.axExtClk, 'X')
            ylabel(app.axExtClk, 'Y')
            app.InvisiblePlotTabs = uitabgroup(app.UIFigure);
            app.InvisiblePlotTabs.Visible = 'off';
            app.Tab = uitab(app.InvisiblePlotTabs);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end